classdef OpenTIPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_OpenTIPS           matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel_Settings        matlab.ui.container.Panel
        Grid_Settings         matlab.ui.container.GridLayout
        Panel_InputPath       matlab.ui.container.Panel
        Grid_InputPath        matlab.ui.container.GridLayout
        EditField_InputPath   matlab.ui.control.EditField
        Button_InputPath      matlab.ui.control.Button
        Panel_OutputPath      matlab.ui.container.Panel
        Grid_OutputPath       matlab.ui.container.GridLayout
        EditField_OutputPath  matlab.ui.control.EditField
        Button_OutPath        matlab.ui.control.Button
        Panel_Parameters      matlab.ui.container.Panel
        Grid_Parameters       matlab.ui.container.GridLayout
        UITable_Parameters    matlab.ui.control.Table
        Button_ROI            matlab.ui.control.Button
        Button_Penalty        matlab.ui.control.Button
        Button_Run            matlab.ui.control.Button
        Button_CalLF          matlab.ui.control.Button
        Panel_eletype         matlab.ui.container.Panel
        GridLayout_eletype    matlab.ui.container.GridLayout
        CheckBox_tri          matlab.ui.control.CheckBox
        CheckBox_tet          matlab.ui.control.CheckBox
        Panel_Plots           matlab.ui.container.Panel
        Grid_Plots            matlab.ui.container.GridLayout
        UIAxes_showROI        matlab.ui.control.UIAxes
        Grid_Logo             matlab.ui.container.GridLayout
        Image_Logo            matlab.ui.control.Image
        ContextMenu           matlab.ui.container.ContextMenu
        ROI1Menu              matlab.ui.container.Menu
        ContextMenu2          matlab.ui.container.ContextMenu
        Menu                  matlab.ui.container.Menu
        ContextMenu3          matlab.ui.container.ContextMenu
        Menu_2                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        InputPath; % The folder of data input
        OutputPath % The folder of data Output   
        DialogROIApp; % The app window for ROI parameters
        DialogPenaltyApp; % The app window for Penalty parameters
        ROIPara = {}; % Parameter for ROI(s)
        PenaltyPara = {}; % Parameter for Penalty area(s)
        MergedPara = {}; % Merged parameters for ROI and Penalty area(s)
        eletype = 'tri';
    end

    methods (Access = public)
        
        function updataROIPara(app,regionname,coordMNI,r,label)
           app.ROIPara = [app.ROIPara;{regionname},{coordMNI},{r},{label}];
           app.MergedPara = [app.MergedPara;{regionname},{label},...
               {[num2str(coordMNI(1)) ',' num2str(coordMNI(2)) ',' num2str(coordMNI(3))]},{r},{1}];
           app.UITable_Parameters.Data = app.MergedPara;
        end
        
        function updataPenaltyPara(app,regionname,coordMNI,r,p,label)
           app.PenaltyPara = [app.PenaltyPara;{regionname},{coordMNI},{r},{p},{label}];
           app.MergedPara = [app.MergedPara;{regionname},{label},...
               {[num2str(coordMNI(1)) ',' num2str(coordMNI(2)) ',' num2str(coordMNI(3))]},{r},{-p}];
           app.UITable_Parameters.Data = app.MergedPara;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_InputPath
        function Button_InputPathPushed(app, event)
            app.InputPath = uigetdir(pwd,'请选择输入数据的存储路径');
            app.EditField_InputPath.Value = app.InputPath;
        end

        % Value changed function: EditField_InputPath
        function EditField_InputPathValueChanged(app, event)
            app.InputPath = app.EditField_InputPath.Value;     
        end

        % Button pushed function: Button_OutPath
        function Button_OutPathPushed(app, event)
            app.OutputPath = uigetdir(pwd,'请选择输出数据的存储路径');
            app.EditField_OutputPath.Value = app.OutputPath;
        end

        % Value changed function: EditField_OutputPath
        function EditField_OutputPathValueChanged(app, event)
            app.OutputPath = app.EditField_OutputPath.Value;             
        end

        % Button pushed function: Button_ROI
        function Button_ROIPushed(app, event)
            if ~isempty(app.InputPath) && ~isempty(app.OutputPath)          
%             app.Button_ROI.Enable = 'off';
            app.DialogROIApp = Dialog_ROI(app,app.InputPath,app.OutputPath,app.eletype);
            else
                uiwait(msgbox('缺少数据路径','错误','modal'));
            end
        end

        % Button pushed function: Button_Penalty
        function Button_PenaltyPushed(app, event)
            if ~isempty(app.InputPath) && ~isempty(app.OutputPath)          
%             app.Button_ROI.Enable = 'off';
            app.DialogPenaltyApp = Dialog_Penalty(app,app.InputPath,app.OutputPath,app.eletype);
            else
                uiwait(msgbox('缺少数据路径','错误','modal'));
            end
        end

        % Value changed function: CheckBox_tri
        function CheckBox_triValueChanged(app, event)
            value = app.CheckBox_tri.Value;
            if value
                app.CheckBox_tet.Value = false;
                app.eletype = 'tri';
            end
        end

        % Value changed function: CheckBox_tet
        function CheckBox_tetValueChanged(app, event)
            value = app.CheckBox_tet.Value;
            if value
                app.CheckBox_tri.Value = false;
                app.eletype = 'tet';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UI_OpenTIPS.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {663, 663};
                app.GridLayout.ColumnWidth = {'1x'};
                app.Panel_Plots.Layout.Row = 2;
                app.Panel_Plots.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {349, '1x'};
                app.Panel_Plots.Layout.Row = 1;
                app.Panel_Plots.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_OpenTIPS = uifigure('Visible', 'off');
            app.UI_OpenTIPS.Name = 'OpenTIPS';
            app.UI_OpenTIPS.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UI_OpenTIPS);
            app.Panel_Settings = uipanel(app.GridLayout);
            app.Grid_Settings = uigridlayout(app.Panel_Settings);
            app.Panel_InputPath = uipanel(app.Grid_Settings);
            app.Panel_InputPath.Title = '输入数据文件路径';
            app.Grid_InputPath = uigridlayout(app.Panel_InputPath);
            app.EditField_InputPath = uieditfield(app.Grid_InputPath, 'text');
            app.EditField_InputPath.ValueChangedFcn = createCallbackFcn(app, @EditField_InputPathValueChanged, true);
            app.Button_InputPath = uibutton(app.Grid_InputPath, 'push');
            app.Button_InputPath.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPathPushed, true);
            app.Button_InputPath.Text = '浏览';
            app.Panel_OutputPath = uipanel(app.Grid_Settings);
            app.Panel_OutputPath.Title = '输出结果文件路径';
            app.Grid_OutputPath = uigridlayout(app.Panel_OutputPath);
            app.EditField_OutputPath = uieditfield(app.Grid_OutputPath, 'text');
            app.EditField_OutputPath.ValueChangedFcn = createCallbackFcn(app, @EditField_OutputPathValueChanged, true);
            app.Button_OutPath = uibutton(app.Grid_OutputPath, 'push');
            app.Button_OutPath.ButtonPushedFcn = createCallbackFcn(app, @Button_OutPathPushed, true);
            app.Button_OutPath.Text = '浏览';
            app.Panel_Parameters = uipanel(app.Grid_Settings);
            app.Panel_Parameters.Title = '设置仿真参数';
            app.Grid_Parameters = uigridlayout(app.Panel_Parameters);
            app.UITable_Parameters = uitable(app.Grid_Parameters);
            app.UITable_Parameters.ColumnName = {'脑区名称'; '标签'; '中心MNI坐标'; '半径'; '系数'};
            app.UITable_Parameters.RowName = {};
            app.Button_ROI = uibutton(app.Grid_Settings, 'push');
            app.Button_ROI.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIPushed, true);
            app.Button_ROI.Text = '添加目标脑区';
            app.Button_Penalty = uibutton(app.Grid_Settings, 'push');
            app.Button_Penalty.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyPushed, true);
            app.Button_Penalty.Text = '添加惩罚脑区';
            app.Button_Run = uibutton(app.Grid_Settings, 'push');
            app.Button_Run.Text = '开始计算';
            app.Button_CalLF = uibutton(app.Grid_Settings, 'push');
            app.Button_CalLF.Text = '模型构建';
            app.Panel_eletype = uipanel(app.Grid_Settings);
            app.Panel_eletype.Title = '计算方法';
            app.GridLayout_eletype = uigridlayout(app.Panel_eletype);
            app.CheckBox_tri = uicheckbox(app.GridLayout_eletype);
            app.CheckBox_tri.ValueChangedFcn = createCallbackFcn(app, @CheckBox_triValueChanged, true);
            app.CheckBox_tri.Text = '快速';
            app.CheckBox_tri.Value = true;
            app.CheckBox_tet = uicheckbox(app.GridLayout_eletype);
            app.CheckBox_tet.ValueChangedFcn = createCallbackFcn(app, @CheckBox_tetValueChanged, true);
            app.CheckBox_tet.Text = '精确';
            app.Panel_Plots = uipanel(app.GridLayout);
            app.Grid_Plots = uigridlayout(app.Panel_Plots);
            app.UIAxes_showROI = uiaxes(app.Grid_Plots);
            title(app.UIAxes_showROI, 'Title')
            xlabel(app.UIAxes_showROI, 'X')
            ylabel(app.UIAxes_showROI, 'Y')
            app.Grid_Logo = uigridlayout(app.Grid_Plots);
            app.Image_Logo = uiimage(app.Grid_Logo);
            app.Image_Logo.ImageSource = 'opentips.png';
            app.ContextMenu = uicontextmenu(app.UI_OpenTIPS);
            % Assign app.ContextMenu
            app.UITable_Parameters.ContextMenu = app.ContextMenu;
            app.ROI1Menu = uimenu(app.ContextMenu);
            app.ROI1Menu.Text = '系数的含义：ROI脑区的系数为1，惩罚脑区的系数为 -（惩罚系数）';
            app.ContextMenu2 = uicontextmenu(app.UI_OpenTIPS);
            % Assign app.ContextMenu2
            app.CheckBox_tri.ContextMenu = app.ContextMenu2;
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = '基于灰质中间层表面进行计算';
            app.ContextMenu3 = uicontextmenu(app.UI_OpenTIPS);
            % Assign app.ContextMenu3
            app.CheckBox_tet.ContextMenu = app.ContextMenu3;
            app.Menu_2 = uimenu(app.ContextMenu3);
            app.Menu_2.Text = '基于四面体体素进行计算，所需计算时间大约是快速数据的10倍';
            % Show the figure after all components are created
            app.UI_OpenTIPS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenTIPS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_OpenTIPS)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_OpenTIPS)
        end
    end
end