classdef TIPSconfig_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_OpenTIPS                   matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        Panel_Settings                matlab.ui.container.Panel
        Grid_Settings                 matlab.ui.container.GridLayout
        ROIPanel                      matlab.ui.container.Panel
        Button_ROIDisplay             matlab.ui.control.Button
        Button_ROIMinus               matlab.ui.control.Button
        Button_ROIAdd                 matlab.ui.control.Button
        UITable_ROI                   matlab.ui.control.Table
        PenaltyPanel                  matlab.ui.container.Panel
        Button_PenaltyDisplay         matlab.ui.control.Button
        Button_PenaltyMinus           matlab.ui.control.Button
        Button_PenaltyAdd             matlab.ui.control.Button
        UITable_Penalty               matlab.ui.control.Table
        OptimalcoefficientsPanel      matlab.ui.container.Panel
        coefficient                   matlab.ui.container.GridLayout
        electrodenumberDropDown       matlab.ui.control.DropDown
        electrodenumberDropDownLabel  matlab.ui.control.Label
        electrodecandidateEditField   matlab.ui.control.NumericEditField
        electrodecandidateEditFieldLabel  matlab.ui.control.Label
        ConfigurationButton           matlab.ui.control.Button
        PenaltycoefEditField          matlab.ui.control.NumericEditField
        PenaltycoefEditFieldLabel     matlab.ui.control.Label
        OtheralphaEditField           matlab.ui.control.NumericEditField
        OtheralphaEditFieldLabel      matlab.ui.control.Label
        ROIalphaEditField             matlab.ui.control.NumericEditField
        ROIalphaEditFieldLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        OrientationPanel              matlab.ui.container.Panel
        OrientationLamp               matlab.ui.control.Lamp
        OrientationCheckBox           matlab.ui.control.CheckBox
        Panel_dataRoot                matlab.ui.container.Panel
        Grid_dataRoot                 matlab.ui.container.GridLayout
        Button_dataRoot               matlab.ui.control.Button
        EditField_dataRoot            matlab.ui.control.EditField
        Panel_subMark                 matlab.ui.container.Panel
        subMarkLamp                   matlab.ui.control.Lamp
        DropDown_subMark              matlab.ui.control.DropDown
        Panel_simMark                 matlab.ui.container.Panel
        EditField_simMark             matlab.ui.control.EditField
        ROIthreshold                  matlab.ui.container.ButtonGroup
        ROIthresEditField             matlab.ui.control.NumericEditField
        VmEditFieldLabel              matlab.ui.control.Label
        Panel_Plots                   matlab.ui.container.Panel
        Grid_Plots                    matlab.ui.container.GridLayout
        Panel_cfgImport               matlab.ui.container.Panel
        Grid_cfgImport                matlab.ui.container.GridLayout
        Button_cfgImport              matlab.ui.control.Button
        EditField_cfgImport           matlab.ui.control.EditField
        Image_Logo                    matlab.ui.control.Image
        UIAxes_figure                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        subList;
        %% display
        gmsTR;
        ROI_TR;
        Penalty_TR;
        %% Color
        ROIColor = [139,0,0];
        PenaltyColor = [0,0,139];
        %%
        ROIview;
        hobj;
        targetNew;
        varTypes = ["string","double","string","double"];
        varNames = ["Name","CoordMNI","Shape","Radius"];
        targetUINew;
        cfgFile;
        %% status
        GrayMidSurface_status = false;
        TetLeadfield_status = false;
        Headreco_status = false;
        dataRoot_status = true;
        WMnt_status = false;
        GMnt_status = false;
        %% msg
        Headreco_error_msg = 'Headreco meshing in simNIBS failed';
        TetLeadfield_error_msg = 'The tetraheron leadfield is not available.';
        GrayMidSurface_error_msg = 'No Gray matter middle surface for display.';

    end

    properties (Access = public)
        dataRoot = 'C:\Users\psylab706\Documents\simnibs_examples';
        subMark = 'ernie';
        simDir;
        m2mPath;
        simMark;
        mshMeshFile;
        GMSFile;
        tetLFFile;
        WMntFile;
        GMntFile;
        cfg;
        row_ROI_table_selected;
        row_Penalty_table_selected;
    end

    methods (Access = public)
        function updateTarget(app,regionName,coordMNI,r,shapeType)
            app.targetNew = {regionName,coordMNI,shapeType,r};
            app.targetUINew = {regionName,num2str(coordMNI),shapeType,num2str(r)};
        end
        function update_TetLeadfieldQuery(app,status)
            app.TetLeadfield_status = status;
        end
        
        function update_HeadrecoQuery(app,status)
            app.Headreco_status = status;
        end
        function update_WMntQuery(app,status)
            app.WMnt_status = status;
        end
        function update_GMntQuery(app,status)
            app.GMnt_status = status;
        end
          function update_GMS_Query(app,status)
            app.GrayMidSurface_status = status;
        end
    end

    methods (Access = private)

        function UpdateSublist(app)
            d = dir(app.dataRoot);
            f = {d([d.isdir]).name};
            app.subList = ['Undefined',f(3:end)]';
            app.DropDown_subMark.Items = app.subList;
        end

        function SubMarkDefault(app)
            %% subMark
            app.subMarkLamp.Color = [1,0,0];
            app.m2mPath = '';
            %% simMark
            app.simMark = '';
            app.EditField_simMark.Value = app.simMark;
            app.simDir = '';
            %% thres
            app.cfg.thres = 0.2;
            app.ROIthresEditField.Value = app.cfg.thres;
            %% Orientation
            app.cfg.nt = false;
            app.OrientationCheckBox.Value = 0;
            app.WMnt_status = false;
            app.WMntFile = '';
            app.GMnt_status = false;
            app.GMntFile = '';
            app.OrientationLamp.Color = [1,0,0];
            %% ROI table
            app.cfg.ROI.num = 0;
            app.cfg.ROI.table = table('Size',[0,4],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.UITable_ROI.Data = [];
            %% Penalty table
            app.cfg.Penalty.num = 0;
            app.cfg.Penalty.table = table('Size',[0,4],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.UITable_ROI.Data = [];

            app.cfg.method_ROI = 0;
            app.ROIalphaEditField.Value = app.cfg.method_ROI;

            app.cfg.method_Other = 2;
            app.OtheralphaEditField.Value = app.cfg.method_Other;

            app.cfg.Penalty.coef = 2;
            app.PenaltycoefEditField.Value = app.cfg.Penalty.coef;

            app.cfg.elecNum = 4;
            app.electrodenumberDropDown.Value = app.electrodenumberDropDown.Items{1};

            app.cfg.elecCandNum = 0;
            app.electrodecandidateEditField.Value = app.cfg.elecCandNum;
            %% headreco
            app.Headreco_status = false;
            app.mshMeshFile = '';
            %%　gray matter surface
            app.GrayMidSurface_status = false;
            app.GMSFile = '';
            %% tet leadfield
            app.TetLeadfield_status = false;
            app.tetLFFile = '';
            %%  TR
            app.gmsTR = [];
            app.ROI_TR = [];
            app.Penalty_TR = [];
            cla(app.UIAxes_figure);
            title(app.UIAxes_figure,app.subMark);
        end

        function ImportCfg(app)
            %% read
            S = load(app.cfgFile);
            app.cfg = S.cfg;
            %% dataRoot
            app.dataRoot = app.cfg.dataRoot;
            app.EditField_dataRoot.Value = app.dataRoot;
            UpdateSublist(app);
            %% subMark
            app.subMark = app.cfg.subMark;
            if ~ismember(app.subMark,app.subList)
                app.subMark = app.subList{1};
            end
            app.DropDown_subMark.Value = app.subMark;
            if strcmp(app.subMark,'Undefined')
                SubMarkDefault(app);
                msgbox('The import cfg has wrong subMark.');
                disp('The import cfg has wrong subMark.');
            else
                app.simMark = app.cfg.simMark;
                app.EditField_simMark.Value = app.simMark;
                SubMarkUpdate(app);
                %% update parameters
                if app.cfg.nt
                    app.OrientationCheckBox.Value = 1;
                end
                %% ROI
                if app.cfg.ROI.num>0
                    app.UITable_ROI.Data = [app.cfg.ROI.table.Name,...
                        num2str(app.cfg.ROI.table.CoordMNI),...
                        app.cfg.ROI.table.Shape,...;
                        num2str(app.cfg.ROI.table.Radius)];
                end
                %% Penalty
                if app.cfg.Penalty.num>0
                    app.UITable_Penalty.Data = [app.cfg.Penalty.table.Name,...
                        num2str(app.cfg.Penalty.table.CoordMNI),...
                        app.cfg.Penalty.table.Shape,...;
                        num2str(app.cfg.Penalty.table.Radius)];
                end
                %% other
                app.electrodenumberDropDown.Value = num2str(app.cfg.elecNum);
                app.electrodecandidateEditField.Value = app.cfg.elecCandNum;
                app.PenaltycoefEditField.Value = app.cfg.Penalty.coef;
                app.OtheralphaEditField.Value = app.cfg.method_Other;
                app.ROIalphaEditField.Value = app.cfg.method_ROI;
                app.ROIthresholdEditField.Value = app.cfg.thres;
            end
        end

        function ShowGMS(app)
            m0 = mesh_load_hdf5(app.GMSFile);
            m1 = m0(2).mesh;
            gmS = mesh_extract_regions(m1, 'region_idx', [1 2]); % gray matter surface in both hemisphere
            app.gmsTR = triangulation(double(gmS.triangles),gmS.nodes);
            app.hobj = trimesh(app.gmsTR.ConnectivityList,app.gmsTR.Points(:,1),app.gmsTR.Points(:,2),app.gmsTR.Points(:,3),'EdgeColor','none',...
                'FaceColor', [130/255 130/255 130/255],'FaceAlpha',0.05,'Parent',app.UIAxes_figure);
            title(app.UIAxes_figure,app.subMark,'FontWeight','normal');
            lighting(app.UIAxes_figure,'gouraud');
            rotate3d(app.UIAxes_figure,'on');
            set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
            axis(app.UIAxes_figure,'off');
            material(app.hobj,'dull');
        end

        function CheckHeadreco(app)
            app.mshMeshFile = fullfile(app.dataRoot,app.subMark,[app.subMark '.msh']);
            if exist(app.mshMeshFile,'file')~=2
                app_headreco = HeadrecoQuery_GUI(app);
                uiwait(app_headreco.UIFigure);
            else
                app.Headreco_status = true;
            end
            if ~app.Headreco_status
                cla(app.UIAxes_figure);
                title(app.UIAxes_figure,[app.subMark ': ' app.Headreco_error_msg],'FontWeight','normal');
                app.subMarkLamp.Color = [1,0,0];
            end
        end

        function CheckGMS(app)
            %% todo
            app.GMSFile = fullfile(app.dataRoot,app.subMark,'leadfield',[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
            %% questdlg
            if exist(app.GMSFile,'file')~=2
                app_GMS = GMSQuery_GUI(app);
                uiwait(app_GMS.UIFigure);
            else
                app.GrayMidSurface_status = true;
            end
            if ~app.GrayMidSurface_status
                        cla(app.UIAxes_figure);
                        app.gmsTR = [];
                    title(app.UIAxes_figure,app.GrayMidSurface_error_msg);
                    disp(app.GrayMidSurface_error_msg);
            else
                ShowGMS(app);
            end
        end

        function CheckDataRoot(app)
            if ~isfolder(app.dataRoot)
                msgbox('Not correct root data directory!')
                app.dataRoot_status = false;
            else
                app.dataRoot_status = true;
            end
        end

        function CheckTetLeadfield(app)
            app.tetLFFile = fullfile(app.dataRoot,app.subMark,'leadfield_tet',[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
            if exist(app.tetLFFile,'file')~=2
                app_TetLeadfield = Target_GUI(app);
                uiwait(app_TetLeadfield.UIFigure);
            else
                app.TetLeadfield_status = true;
            end
            if (app.TetLeadfield_status&&app.Headreco_status)
                app.subMarkLamp.Color = [0,1,0];
            else
                disp(app.TetLeadfield_error_msg);
                app.subMarkLamp.Color = [1,0,0];
            end
        end

        function CheckOrientation(app)
            app.WMntFile = fullfile(app.dataRoot,app.subMark,'orientation','nt_elem_WM.mat');
            if exist(app.WMntFile,'file')~=2
                app_WMnt = WMntQuery_GUI(app);
                uiwait(app_WMnt.UIFigure);
            else
                app.WMnt_status = true;
                disp([app.subMark ' : Orientation file for white matter existed...']);
            end
            app.GMntFile = fullfile(app.dataRoot,app.subMark,'orientation','nt_elem_GM.mat');

            if exist(app.GMntFile,'file')~=2
                app_GMnt = GMntQuery_GUI(app);
                uiwait(app_GMnt.UIFigure);
            else
                app.GMnt_status = true;
                disp([app.subMark ' : Orientation file for gray matter existed...']);
            end
            if(app.WMnt_status&&app.GMnt_status)
                app.OrientationLamp.Color = [0,1,0];
            else
                app.OrientationLamp.Color = [1,0,0];
            end 
        end

        function SubMarkUpdate(app)
            %% change path
            app.m2mPath = fullfile(app.dataRoot,app.subMark, ['m2m_' app.subMark]);
            %%　change figure
            cla(app.UIAxes_figure);
            CheckHeadreco(app);
            if app.Headreco_status
                %% gray matter middle surface display
                CheckGMS(app);
                %% tetrahedron leadfield
                CheckTetLeadfield(app);
            else
                title(app.UIAxes_figure,[app.subMark ': ' app.Headreco_error_msg]);
                disp(app.Headreco_error_msg);
                app.subMarkLamp.Color = [1,0,0];
            end
            %% Orientation
            CheckOrientation(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% dataRoot
            try
                app.dataRoot = evalin('base','dataRoot');
            end
            app.EditField_dataRoot.Value = app.dataRoot;
            if ~isempty(app.dataRoot)
                UpdateSublist(app);
            end
            %% figure
            title(app.UIAxes_figure,'Gray matter brain figure','FontWeight','normal');
            lighting(app.UIAxes_figure,'gouraud');
            rotate3d(app.UIAxes_figure,'on');
            set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
            axis(app.UIAxes_figure,'off');
            material(app.hobj,'dull');
            %% subMark
            try
                app.subMark = evalin('base','subMark');
            end
            if ~ismember(app.subMark,app.subList)
                app.subMark = app.subList{1};
            end
            app.DropDown_subMark.Value = app.subMark;
            if strcmp(app.subMark,'Undefined')
                SubMarkDefault(app);
            else
                try
                    app.simMark = evalin('base','simMark');
                end
                if ~isempty(app.simMark)
                    app.EditField_simMark.Value = app.simMark;
                end
                SubMarkUpdate(app);
            end
        end

        % Button pushed function: Button_dataRoot
        function Button_dataRootPushed(app, event)
            dataRoot_tmp = uigetdir(pwd, 'Data root directory');
            if dataRoot_tmp ~=0
                app.dataRoot = dataRoot_tmp;
                app.EditField_dataRoot.Value = app.dataRoot;
                UpdateSublist(app);
                app.DropDown_subMark.Value = app.subList{1};
            end
        end

        % Value changed function: EditField_dataRoot
        function EditField_dataRootValueChanged(app, event)
            value = app.EditField_dataRoot.Value;
            if ~isempty(value)
                app.dataRoot = value;
                app.EditField_dataRoot.Value = app.dataRoot;
                UpdateSublist(app);
                app.DropDown_subMark.Value = app.subList{1};
            end
        end

        % Value changed function: DropDown_subMark
        function DropDown_subMarkValueChanged(app, event)
            %% default
            app.subMark = app.DropDown_subMark.Value;
            if strcmp(app.subMark,'Undefined')
                SubMarkDefault(app);
            else
                SubMarkUpdate(app);
            end
        end

        % Value changed function: EditField_simMark
        function EditField_simMarkValueChanged(app, event)
            app.simMark = app.EditField_simMark.Value;
            app.simDir = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
            disp(['OutputPath is ' app.simDir]);
            if(isfolder(app.simDir))
                msgbox('This simMark has been used, the old data would be overwritten!')
            end
        end

        % Button pushed function: Button_ROIAdd
        function Button_ROIAddPushed(app, event)
            app.targetNew = [];
            app.targetUINew = [];
            appTarget = Target_GUI(app);
            uiwait(appTarget.UI_DialogTarget);
            %%
            if ~isempty(app.targetNew)
                app.cfg.ROI.num = app.cfg.ROI.num + 1;
                app.cfg.ROI.table = [app.cfg.ROI.table; app.targetNew];
                app.UITable_ROI.Data = [app.UITable_ROI.Data; app.targetUINew];
            end
            disp('ROI table:');
            disp(app.cfg.ROI.table);
        end

        % Button pushed function: Button_PenaltyAdd
        function Button_PenaltyAddPushed(app, event)
            app.targetNew = [];
            app.targetUINew = [];
            appTarget = Target_GUI(app);
            uiwait(appTarget.UI_DialogTarget);
            %%
            if ~isempty(app.targetNew)
                app.cfg.Penalty.num = app.cfg.Penalty.num + 1;
                app.cfg.Penalty.table = [app.cfg.Penalty.table; app.targetNew];
                app.UITable_Penalty.Data = [app.UITable_Penalty.Data; app.targetUINew];
            end
            disp('Penalty table:');
            disp(app.cfg.Penalty.table);
        end

        % Cell selection callback: UITable_ROI
        function UITable_ROICellSelection(app, event)
            if ~isempty(event.Indices)
                app.row_ROI_table_selected = event.Indices(1);
            end
        end

        % Cell selection callback: UITable_Penalty
        function UITable_PenaltyCellSelection(app, event)
            if ~isempty(event.Indices)
                app.row_Penalty_table_selected = event.Indices(1);
            end
        end

        % Button pushed function: Button_ROIMinus
        function Button_ROIMinusPushed(app, event)
            if app.row_ROI_table_selected>0
                app.UITable_ROI.Data(app.row_ROI_table_selected,:) = [];
                app.cfg.ROI.num = app.cfg.ROI.num - 1;
                app.cfg.ROI.table(app.row_ROI_table_selected,:) = [];
                disp('ROI table:');
                disp(app.cfg.ROI.table);
            end
        end

        % Button pushed function: Button_PenaltyMinus
        function Button_PenaltyMinusPushed(app, event)
            if app.row_Penalty_table_selected>0
                app.UITable_Penalty.Data(app.row_Penalty_table_selected,:) = [];
                app.cfg.Penalty.num = app.cfg.Penalty.num - 1;
                app.cfg.Penalty.table(app.row_Penalty_table_selected,:) = [];
                disp('Penalty table:');
                disp(app.cfg.Penalty.table);
            end
        end

        % Button pushed function: Button_ROIDisplay
        function Button_ROIDisplayPushed(app, event)
            [app.ROIview.az,app.ROIview.el]=view(app.UIAxes_figure);
            hold(app.UIAxes_figure,'off');
            cla(app.UIAxes_figure);
            if app.GrayMidSurface_status
                ShowGMS(app);
                %% plot
                view(app.UIAxes_figure,app.ROIview.az,app.ROIview.el);
                hold(app.UIAxes_figure,'on');
                rotate3d(app.UIAxes_figure,'on');
                set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
                axis(app.UIAxes_figure,'off');
                if app.cfg.ROI.num>0
                    ROI_node_idx = false(size(app.gmsTR.Points,1),app.cfg.ROI.num);
                    coord_sub = mni2subject_coords(app.cfg.ROI.table.CoordMNI, app.m2mPath);
                    for i = 1 : app.cfg.ROI.num
                        ROI_node_idx(:,i) = vecnorm(app.gmsTR.Points-coord_sub(i,:),2,2)<app.cfg.ROI.table.Radius(i);
                    end
                    ROI_node_idx = any(ROI_node_idx,2);
                    ROI__idx = sum(ROI_node_idx(app.gmsTR.ConnectivityList),2)>1;
                    app.ROI_TR = simpleTR(triangulation(app.gmsTR.ConnectivityList(ROI_tri_idx,:),app.gmsTR.Points));
                    trimesh(app.ROI_TR.ConnectivityList,app.ROI_TR.Points(:,1),app.ROI_TR.Points(:,2),app.ROI_TR.Points(:,3),'EdgeColor','none',...
                        'FaceColor', app.ROIColor/255,'FaceAlpha',1,'Parent',app.UIAxes_figure);
                end
            end
        end

        % Button pushed function: Button_PenaltyDisplay
        function Button_PenaltyDisplayPushed(app, event)
            if app.GrayMidSurface_status
                hold(app.UIAxes_figure,'on');
                if app.cfg.Penalty.num>0
                    Penalty_node_idx = false(size(app.gmsTR.Points,1),app.cfg.Penalty.num);
                    coord_sub = mni2subject_coords(app.cfg.Penalty.table.CoordMNI, app.m2mPath);
                    for i = 1 : app.cfg.Penalty.num
                        Penalty_node_idx(:,i) = vecnorm(app.gmsTR.Points-coord_sub(i,:),2,2)<app.cfg.Penalty.table.Radius(i);
                    end
                    Penalty_node_idx = any(Penalty_node_idx,2);
                    Penalty_tri_idx = sum(Penalty_node_idx(app.gmsTR.ConnectivityList),2)>1;
                    app.Penalty_TR = simpleTR(triangulation(app.gmsTR.ConnectivityList(Penalty_tri_idx,:),app.gmsTR.Points));
                    trimesh(app.Penalty_TR.ConnectivityList,app.Penalty_TR.Points(:,1),app.Penalty_TR.Points(:,2),app.Penalty_TR.Points(:,3),'EdgeColor','none',...
                        'FaceColor', app.PenaltyColor/255,'FaceAlpha',1,'Parent',app.UIAxes_figure);
                end
            end
        end

        % Value changed function: OrientationCheckBox
        function OrientationCheckBoxValueChanged(app, event)
            app.cfg.nt = app.OrientationCheckBox.Value;
            if app.cfg.nt
                CheckOrientation(app);
                if (app.WMnt_status&&app.GMnt_status)
                    app.OrientationLamp.Color = [0,1,0];
                   app.OrientationCheckBox.Value = true;
                else
                    app.OrientationLamp.Color = [1,0,0];
                     app.cfg.nt = false;
                    app.OrientationCheckBox.Value = false;
                end
            end
        end

        % Value changed function: electrodenumberDropDown
        function electrodenumberDropDownValueChanged(app, event)
            app.cfg.elecNum = app.electrodenumberDropDown.Value;
        end

        % Value changed function: electrodecandidateEditField
        function electrodecandidateEditFieldValueChanged(app, event)
            app.cfg.elecCandNum = app.electrodecandidateEditField.Value;
        end

        % Button pushed function: ConfigurationButton
        function ConfigurationButtonPushed(app, event)
            %% save
            if isempty(app.simMark)
                msgbox('The simMark not allowd empty!','Error');
                status = false;
            else
                app.simDir = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
                if exist(app.simDir,'dir')
                    answer = questdlg('Would you like to overwrite the data under this simMark?', ...
                        'Warning','Yes','No','Yes');
                    switch answer
                        case 'Yes'
                            status = true;
                        case 'No'
                            status = false;
                    end
                else
                    mkdir(app.simDir);
                    status = true;
                end
            end
            if status
                %% Check nt
                CheckHeadreco(app);
                CheckTetLeadfield(app);
                if app.cfg.nt == true
                    CheckOrientation(app);
                end
                %% save cfg and pass it to the base workspace
                app.cfg.dataRoot = app.dataRoot;
                app.cfg.subMark = app.subMark;
                app.cfg.simMark = app.simMark;
                cfg = app.cfg;
                app.cfgFile = fullfile(app.simDir,'cfg.mat');
                save(app.cfgFile,'cfg');
                assignin('base','cfg',cfg);
                disp('Please eval "OpenTIPS(cfg)" in main prompt command line.')
                delete(app);
            end

        end

        % Value changed function: PenaltycoefEditField
        function PenaltycoefEditFieldValueChanged(app, event)
            app.cfg.Penalty.coef = app.PenaltycoefEditField.Value;
        end

        % Value changed function: OtheralphaEditField
        function OtheralphaEditFieldValueChanged(app, event)
            app.cfg.method_Other = app.OtheralphaEditField.Value;
        end

        % Value changed function: ROIalphaEditField
        function ROIalphaEditFieldValueChanged(app, event)
            app.cfg.method_ROI = app.ROIalphaEditField.Value;
        end

        % Callback function
        function ROIthresholdEditFieldValueChanged(app, event)
            app.cfg.thres = app.ROIthresholdEditField.Value;
        end

        % Button pushed function: Button_cfgImport
        function Button_cfgImportPushed(app, event)
            if ~isempty(app.dataRoot)
                if ~strcmp('Undefined',app.DropDown_subMark.Value)
                    defaultDir = fullfile(app.dataRoot,app.subMark);
                else
                    defaultDir = app.dataRoot;
                end
            else
                defaultDir = pwd;
            end
            [file,dir] = uigetfile(defaultDir, 'Import existed configuration file');
            app.cfgFile = fullfile(dir,file);
            app.EditField_cfgImport.Value = app.cfgFile;
            if app.cfgFile ~=0
                ImportCfg(app);
            end
        end

        % Value changed function: EditField_cfgImport
        function EditField_cfgImportValueChanged(app, event)
            app.cfgFile = app.EditField_cfgImport.Value;
            ImportCfg(app);
        end

        % Value changed function: ROIthresEditField
        function ROIthresEditFieldValueChanged(app, event)
            app.cfg.thres = app.ROIthresEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UI_OpenTIPS.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {663, 663};
                app.GridLayout.ColumnWidth = {'1x'};
                app.Panel_Plots.Layout.Row = 2;
                app.Panel_Plots.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {450, '1x'};
                app.Panel_Plots.Layout.Row = 1;
                app.Panel_Plots.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_OpenTIPS = uifigure('Visible', 'off');
            app.UI_OpenTIPS.Name = 'OpenTIPS';
            app.UI_OpenTIPS.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UI_OpenTIPS);
            app.Panel_Settings = uipanel(app.GridLayout);
            app.Grid_Settings = uigridlayout(app.Panel_Settings);
            app.ROIthreshold = uibuttongroup(app.Grid_Settings);
            app.ROIthreshold.Title = 'ROI threshold';
            app.VmEditFieldLabel = uilabel(app.ROIthreshold);
            app.VmEditFieldLabel.Text = 'V/m';
            app.ROIthresEditField = uieditfield(app.ROIthreshold, 'numeric');
            app.ROIthresEditField.ValueChangedFcn = createCallbackFcn(app, @ROIthresEditFieldValueChanged, true);
            app.ROIthresEditField.Value = 0.2;
            app.Panel_simMark = uipanel(app.Grid_Settings);
            app.Panel_simMark.Title = 'simMark';
            app.EditField_simMark = uieditfield(app.Panel_simMark, 'text');
            app.EditField_simMark.ValueChangedFcn = createCallbackFcn(app, @EditField_simMarkValueChanged, true);
            app.Panel_subMark = uipanel(app.Grid_Settings);
            app.Panel_subMark.Title = 'Subject';
            app.DropDown_subMark = uidropdown(app.Panel_subMark);
            app.DropDown_subMark.Items = {'Undefined'};
            app.DropDown_subMark.ValueChangedFcn = createCallbackFcn(app, @DropDown_subMarkValueChanged, true);
            app.DropDown_subMark.Value = 'Undefined';
            app.subMarkLamp = uilamp(app.Panel_subMark);
            app.Panel_dataRoot = uipanel(app.Grid_Settings);
            app.Panel_dataRoot.Title = 'Data root';
            app.Grid_dataRoot = uigridlayout(app.Panel_dataRoot);
            app.EditField_dataRoot = uieditfield(app.Grid_dataRoot, 'text');
            app.EditField_dataRoot.ValueChangedFcn = createCallbackFcn(app, @EditField_dataRootValueChanged, true);
            app.Button_dataRoot = uibutton(app.Grid_dataRoot, 'push');
            app.Button_dataRoot.ButtonPushedFcn = createCallbackFcn(app, @Button_dataRootPushed, true);
            app.Button_dataRoot.Text = '...';
            app.OrientationPanel = uipanel(app.Grid_Settings);
            app.OrientationPanel.Title = 'Orientation';
            app.OrientationCheckBox = uicheckbox(app.OrientationPanel);
            app.OrientationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientationCheckBoxValueChanged, true);
            app.OrientationCheckBox.Text = 'gray and white matter';
            app.OrientationLamp = uilamp(app.OrientationPanel);
            app.Panel = uipanel(app.Grid_Settings);
            app.OptimalcoefficientsPanel = uipanel(app.Grid_Settings);
            app.OptimalcoefficientsPanel.Title = 'Optimal coefficients';
            app.coefficient = uigridlayout(app.OptimalcoefficientsPanel);
            app.ROIalphaEditFieldLabel = uilabel(app.coefficient);
            app.ROIalphaEditFieldLabel.Text = 'ROI alpha';
            app.ROIalphaEditField = uieditfield(app.coefficient, 'numeric');
            app.ROIalphaEditField.ValueChangedFcn = createCallbackFcn(app, @ROIalphaEditFieldValueChanged, true);
            app.OtheralphaEditFieldLabel = uilabel(app.coefficient);
            app.OtheralphaEditFieldLabel.Text = 'Other alpha';
            app.OtheralphaEditField = uieditfield(app.coefficient, 'numeric');
            app.OtheralphaEditField.ValueChangedFcn = createCallbackFcn(app, @OtheralphaEditFieldValueChanged, true);
            app.OtheralphaEditField.Value = 2;
            app.PenaltycoefEditFieldLabel = uilabel(app.coefficient);
            app.PenaltycoefEditFieldLabel.Text = 'Penalty coef';
            app.PenaltycoefEditField = uieditfield(app.coefficient, 'numeric');
            app.PenaltycoefEditField.ValueChangedFcn = createCallbackFcn(app, @PenaltycoefEditFieldValueChanged, true);
            app.PenaltycoefEditField.Value = 2;
            app.ConfigurationButton = uibutton(app.coefficient, 'push');
            app.ConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigurationButtonPushed, true);
            app.ConfigurationButton.Text = 'Configuration';
            app.electrodecandidateEditFieldLabel = uilabel(app.coefficient);
            app.electrodecandidateEditFieldLabel.Text = 'electrode candidate';
            app.electrodecandidateEditField = uieditfield(app.coefficient, 'numeric');
            app.electrodecandidateEditField.ValueChangedFcn = createCallbackFcn(app, @electrodecandidateEditFieldValueChanged, true);
            app.electrodenumberDropDownLabel = uilabel(app.coefficient);
            app.electrodenumberDropDownLabel.Text = {'electrode number'; ''};
            app.electrodenumberDropDown = uidropdown(app.coefficient);
            app.electrodenumberDropDown.Items = {'4', '6'};
            app.electrodenumberDropDown.ValueChangedFcn = createCallbackFcn(app, @electrodenumberDropDownValueChanged, true);
            app.electrodenumberDropDown.Value = '4';
            app.PenaltyPanel = uipanel(app.Grid_Settings);
            app.PenaltyPanel.Title = 'Penalty';
            app.UITable_Penalty = uitable(app.PenaltyPanel);
            app.UITable_Penalty.ColumnName = {'Name'; 'MNI_XYZ'; 'Shape'; 'Radius'};
            app.UITable_Penalty.RowName = {'1'};
            app.UITable_Penalty.CellSelectionCallback = createCallbackFcn(app, @UITable_PenaltyCellSelection, true);
            app.Button_PenaltyAdd = uibutton(app.PenaltyPanel, 'push');
            app.Button_PenaltyAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyAddPushed, true);
            app.Button_PenaltyAdd.Text = '+';
            app.Button_PenaltyMinus = uibutton(app.PenaltyPanel, 'push');
            app.Button_PenaltyMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyMinusPushed, true);
            app.Button_PenaltyMinus.Text = '-';
            app.Button_PenaltyDisplay = uibutton(app.PenaltyPanel, 'push');
            app.Button_PenaltyDisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyDisplayPushed, true);
            app.Button_PenaltyDisplay.Text = 'D';
            app.ROIPanel = uipanel(app.Grid_Settings);
            app.ROIPanel.Title = 'ROI';
            app.UITable_ROI = uitable(app.ROIPanel);
            app.UITable_ROI.ColumnName = {'Name'; 'MNI_XYZ'; 'Shape'; 'Radius'};
            app.UITable_ROI.RowName = {'1'};
            app.UITable_ROI.CellSelectionCallback = createCallbackFcn(app, @UITable_ROICellSelection, true);
            app.Button_ROIAdd = uibutton(app.ROIPanel, 'push');
            app.Button_ROIAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIAddPushed, true);
            app.Button_ROIAdd.Text = '+';
            app.Button_ROIMinus = uibutton(app.ROIPanel, 'push');
            app.Button_ROIMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIMinusPushed, true);
            app.Button_ROIMinus.Text = '-';
            app.Button_ROIDisplay = uibutton(app.ROIPanel, 'push');
            app.Button_ROIDisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIDisplayPushed, true);
            app.Button_ROIDisplay.Text = 'D';
            app.Panel_Plots = uipanel(app.GridLayout);
            app.Grid_Plots = uigridlayout(app.Panel_Plots);
            app.UIAxes_figure = uiaxes(app.Grid_Plots);
            title(app.UIAxes_figure, 'Title')
            xlabel(app.UIAxes_figure, 'X')
            ylabel(app.UIAxes_figure, 'Y')
            app.Image_Logo = uiimage(app.Grid_Plots);
            app.Image_Logo.ImageSource = 'opentips.png';
            app.Panel_cfgImport = uipanel(app.Grid_Plots);
            app.Panel_cfgImport.Title = 'Import configuration';
            app.Grid_cfgImport = uigridlayout(app.Panel_cfgImport);
            app.EditField_cfgImport = uieditfield(app.Grid_cfgImport, 'text');
            app.EditField_cfgImport.ValueChangedFcn = createCallbackFcn(app, @EditField_cfgImportValueChanged, true);
            app.Button_cfgImport = uibutton(app.Grid_cfgImport, 'push');
            app.Button_cfgImport.ButtonPushedFcn = createCallbackFcn(app, @Button_cfgImportPushed, true);
            app.Button_cfgImport.Text = '...';
            % Show the figure after all components are created
            app.UI_OpenTIPS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIPSconfig_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_OpenTIPS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_OpenTIPS)
        end
    end
end