classdef Target_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_DialogTarget       matlab.ui.Figure
        Grid_DialogTarget     matlab.ui.container.GridLayout
        Panel_Target          matlab.ui.container.Panel
        Grid_Target           matlab.ui.container.GridLayout
        DropDown_RegionLabel  matlab.ui.control.DropDown
        Label_Name            matlab.ui.control.Label
        EditField_Name        matlab.ui.control.EditField
        DropDown_Atlas        matlab.ui.control.DropDown
        CenterLabel           matlab.ui.control.Label
        ShapeButtonGroup      matlab.ui.container.ButtonGroup
        SphereButton          matlab.ui.control.RadioButton
        DefaultButton         matlab.ui.control.RadioButton
        ShapeLabel            matlab.ui.control.Label
        ZLabel                matlab.ui.control.Label
        YLabel                matlab.ui.control.Label
        XLabel                matlab.ui.control.Label
        ZEditField            matlab.ui.control.NumericEditField
        YEditField            matlab.ui.control.NumericEditField
        XEditField            matlab.ui.control.NumericEditField
        Button_Cancel         matlab.ui.control.Button
        Button_OK             matlab.ui.control.Button
        EditField_r           matlab.ui.control.NumericEditField
        RadiusLabel           matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        AtlasLabel            matlab.ui.control.Label
        mmLabel               matlab.ui.control.Label
        mmLabel_2             matlab.ui.control.Label
    end


    properties (Access = private)
        atlasList = {'Undefined','AAL3','DK40','a2009s','HCP_MMP1'};
        atlas = 'Undefined';
        atlasDir;
        atlasTable;
        toolboxDir;
    end

    properties (Access = public)
        regionName = 'Undefined';
        coordMNI = [0 0 0];
        ShapeType = 'Sphere';
        r = 10; % Description
        mainApp;
    end
    methods (Access = private)

        function DefaultSetting(app)
            app.EditField_Name.Value = 'Undefined';
            app.EditField_r.Value = 10;
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            app.ZEditField.Value = 0;
            app.SphereButton.Value = true;
            app.toolboxDir = fileparts(mfilename('fullpath'));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % atlasList
%             app.type = mainapp.cfg.type;
            app.DropDown_Atlas.Items = app.atlasList;
            app.DropDown_Atlas.Value = app.DropDown_Atlas.Items{1};
            %% import main app
            app.mainApp = mainapp;
            %% default value setting
            DefaultSetting(app);
        end

        % Value changed function: DropDown_Atlas
        function DropDown_AtlasValueChanged(app, event)
            app.atlas = app.DropDown_Atlas.Value;
            switch app.atlas
                case 'DK40'
                    app.DropDown_RegionLabel.Items = {'自定义' 'lh.unknown'	'lh.bankssts'	'lh.caudalanteriorcingulate'	'lh.caudalmiddlefrontal'	'lh.corpuscallosum'	'lh.cuneus'	'lh.entorhinal'	'lh.fusiform'	'lh.inferiorparietal'	'lh.inferiortemporal'	'lh.isthmuscingulate'	'lh.lateraloccipital'	'lh.lateralorbitofrontal'	'lh.lingual'	'lh.medialorbitofrontal'	'lh.middletemporal'	'lh.parahippocampal'	'lh.paracentral'	'lh.parsopercularis'	'lh.parsorbitalis'	'lh.parstriangularis'	'lh.pericalcarine'	'lh.postcentral'	'lh.posteriorcingulate'	'lh.precentral'	'lh.precuneus'	'lh.rostralanteriorcingulate'	'lh.rostralmiddlefrontal'	'lh.superiorfrontal'	'lh.superiorparietal'	'lh.superiortemporal'	'lh.supramarginal'	'lh.frontalpole'	'lh.temporalpole'	'lh.transversetemporal'	'lh.insula'	'rh.unknown'	'rh.bankssts'	'rh.caudalanteriorcingulate'	'rh.caudalmiddlefrontal'	'rh.corpuscallosum'	'rh.cuneus'	'rh.entorhinal'	'rh.fusiform'	'rh.inferiorparietal'	'rh.inferiortemporal'	'rh.isthmuscingulate'	'rh.lateraloccipital'	'rh.lateralorbitofrontal'	'rh.lingual'	'rh.medialorbitofrontal'	'rh.middletemporal'	'rh.parahippocampal'	'rh.paracentral'	'rh.parsopercularis'	'rh.parsorbitalis'	'rh.parstriangularis'	'rh.pericalcarine'	'rh.postcentral'	'rh.posteriorcingulate'	'rh.precentral'	'rh.precuneus'	'rh.rostralanteriorcingulate'	'rh.rostralmiddlefrontal'	'rh.superiorfrontal'	'rh.superiorparietal'	'rh.superiortemporal'	'rh.supramarginal'	'rh.frontalpole'	'rh.temporalpole'	'rh.transversetemporal'	'rh.insula'};
                    app.DropDown_RegionLabel.ItemsData = {'自定义' 'lh.unknown'	'lh.bankssts'	'lh.caudalanteriorcingulate'	'lh.caudalmiddlefrontal'	'lh.corpuscallosum'	'lh.cuneus'	'lh.entorhinal'	'lh.fusiform'	'lh.inferiorparietal'	'lh.inferiortemporal'	'lh.isthmuscingulate'	'lh.lateraloccipital'	'lh.lateralorbitofrontal'	'lh.lingual'	'lh.medialorbitofrontal'	'lh.middletemporal'	'lh.parahippocampal'	'lh.paracentral'	'lh.parsopercularis'	'lh.parsorbitalis'	'lh.parstriangularis'	'lh.pericalcarine'	'lh.postcentral'	'lh.posteriorcingulate'	'lh.precentral'	'lh.precuneus'	'lh.rostralanteriorcingulate'	'lh.rostralmiddlefrontal'	'lh.superiorfrontal'	'lh.superiorparietal'	'lh.superiortemporal'	'lh.supramarginal'	'lh.frontalpole'	'lh.temporalpole'	'lh.transversetemporal'	'lh.insula'	'rh.unknown'	'rh.bankssts'	'rh.caudalanteriorcingulate'	'rh.caudalmiddlefrontal'	'rh.corpuscallosum'	'rh.cuneus'	'rh.entorhinal'	'rh.fusiform'	'rh.inferiorparietal'	'rh.inferiortemporal'	'rh.isthmuscingulate'	'rh.lateraloccipital'	'rh.lateralorbitofrontal'	'rh.lingual'	'rh.medialorbitofrontal'	'rh.middletemporal'	'rh.parahippocampal'	'rh.paracentral'	'rh.parsopercularis'	'rh.parsorbitalis'	'rh.parstriangularis'	'rh.pericalcarine'	'rh.postcentral'	'rh.posteriorcingulate'	'rh.precentral'	'rh.precuneus'	'rh.rostralanteriorcingulate'	'rh.rostralmiddlefrontal'	'rh.superiorfrontal'	'rh.superiorparietal'	'rh.superiortemporal'	'rh.supramarginal'	'rh.frontalpole'	'rh.temporalpole'	'rh.transversetemporal'	'rh.insula'};
                    app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.ItemsData{1};
                case 'a2009s'
                    app.DropDown_RegionLabel.Items = {'自定义' 'lh.Unknown'	'lh.G_and_S_frontomargin'	'lh.G_and_S_occipital_inf'	'lh.G_and_S_paracentral'	'lh.G_and_S_subcentral'	'lh.G_and_S_transv_frontopol'	'lh.G_and_S_cingul-Ant'	'lh.G_and_S_cingul-Mid-Ant'	'lh.G_and_S_cingul-Mid-Post'	'lh.G_cingul-Post-dorsal'	'lh.G_cingul-Post-ventral'	'lh.G_cuneus'	'lh.G_front_inf-Opercular'	'lh.G_front_inf-Orbital'	'lh.G_front_inf-Triangul'	'lh.G_front_middle'	'lh.G_front_sup'	'lh.G_Ins_lg_and_S_cent_ins'	'lh.G_insular_short'	'lh.G_occipital_middle'	'lh.G_occipital_sup'	'lh.G_oc-temp_lat-fusifor'	'lh.G_oc-temp_med-Lingual'	'lh.G_oc-temp_med-Parahip'	'lh.G_orbital'	'lh.G_pariet_inf-Angular'	'lh.G_pariet_inf-Supramar'	'lh.G_parietal_sup'	'lh.G_postcentral'	'lh.G_precentral'	'lh.G_precuneus'	'lh.G_rectus'	'lh.G_subcallosal'	'lh.G_temp_sup-G_T_transv'	'lh.G_temp_sup-Lateral'	'lh.G_temp_sup-Plan_polar'	'lh.G_temp_sup-Plan_tempo'	'lh.G_temporal_inf'	'lh.G_temporal_middle'	'lh.Lat_Fis-ant-Horizont'	'lh.Lat_Fis-ant-Vertical'	'lh.Lat_Fis-post'	'lh.Medial_wall'	'lh.Pole_occipital'	'lh.Pole_temporal'	'lh.S_calcarine'	'lh.S_central'	'lh.S_cingul-Marginalis'	'lh.S_circular_insula_ant'	'lh.S_circular_insula_inf'	'lh.S_circular_insula_sup'	'lh.S_collat_transv_ant'	'lh.S_collat_transv_post'	'lh.S_front_inf'	'lh.S_front_middle'	'lh.S_front_sup'	'lh.S_interm_prim-Jensen'	'lh.S_intrapariet_and_P_trans'	'lh.S_oc_middle_and_Lunatus'	'lh.S_oc_sup_and_transversal'	'lh.S_occipital_ant'	'lh.S_oc-temp_lat'	'lh.S_oc-temp_med_and_Lingual'	'lh.S_orbital_lateral'	'lh.S_orbital_med-olfact'	'lh.S_orbital-H_Shaped'	'lh.S_parieto_occipital'	'lh.S_pericallosal'	'lh.S_postcentral'	'lh.S_precentral-inf-part'	'lh.S_precentral-sup-part'	'lh.S_suborbital'	'lh.S_subparietal'	'lh.S_temporal_inf'	'lh.S_temporal_sup'	'lh.S_temporal_transverse'	'rh.Unknown'	'rh.G_and_S_frontomargin'	'rh.G_and_S_occipital_inf'	'rh.G_and_S_paracentral'	'rh.G_and_S_subcentral'	'rh.G_and_S_transv_frontopol'	'rh.G_and_S_cingul-Ant'	'rh.G_and_S_cingul-Mid-Ant'	'rh.G_and_S_cingul-Mid-Post'	'rh.G_cingul-Post-dorsal'	'rh.G_cingul-Post-ventral'	'rh.G_cuneus'	'rh.G_front_inf-Opercular'	'rh.G_front_inf-Orbital'	'rh.G_front_inf-Triangul'	'rh.G_front_middle'	'rh.G_front_sup'	'rh.G_Ins_lg_and_S_cent_ins'	'rh.G_insular_short'	'rh.G_occipital_middle'	'rh.G_occipital_sup'	'rh.G_oc-temp_lat-fusifor'	'rh.G_oc-temp_med-Lingual'	'rh.G_oc-temp_med-Parahip'	'rh.G_orbital'	'rh.G_pariet_inf-Angular'	'rh.G_pariet_inf-Supramar'	'rh.G_parietal_sup'	'rh.G_postcentral'	'rh.G_precentral'	'rh.G_precuneus'	'rh.G_rectus'	'rh.G_subcallosal'	'rh.G_temp_sup-G_T_transv'	'rh.G_temp_sup-Lateral'	'rh.G_temp_sup-Plan_polar'	'rh.G_temp_sup-Plan_tempo'	'rh.G_temporal_inf'	'rh.G_temporal_middle'	'rh.Lat_Fis-ant-Horizont'	'rh.Lat_Fis-ant-Vertical'	'rh.Lat_Fis-post'	'rh.Medial_wall'	'rh.Pole_occipital'	'rh.Pole_temporal'	'rh.S_calcarine'	'rh.S_central'	'rh.S_cingul-Marginalis'	'rh.S_circular_insula_ant'	'rh.S_circular_insula_inf'	'rh.S_circular_insula_sup'	'rh.S_collat_transv_ant'	'rh.S_collat_transv_post'	'rh.S_front_inf'	'rh.S_front_middle'	'rh.S_front_sup'	'rh.S_interm_prim-Jensen'	'rh.S_intrapariet_and_P_trans'	'rh.S_oc_middle_and_Lunatus'	'rh.S_oc_sup_and_transversal'	'rh.S_occipital_ant'	'rh.S_oc-temp_lat'	'rh.S_oc-temp_med_and_Lingual'	'rh.S_orbital_lateral'	'rh.S_orbital_med-olfact'	'rh.S_orbital-H_Shaped'	'rh.S_parieto_occipital'	'rh.S_pericallosal'	'rh.S_postcentral'	'rh.S_precentral-inf-part'	'rh.S_precentral-sup-part'	'rh.S_suborbital'	'rh.S_subparietal'	'rh.S_temporal_inf'	'rh.S_temporal_sup'	'rh.S_temporal_transverse'};
                    app.DropDown_RegionLabel.ItemsData = {'自定义' 'lh.Unknown'	'lh.G_and_S_frontomargin'	'lh.G_and_S_occipital_inf'	'lh.G_and_S_paracentral'	'lh.G_and_S_subcentral'	'lh.G_and_S_transv_frontopol'	'lh.G_and_S_cingul-Ant'	'lh.G_and_S_cingul-Mid-Ant'	'lh.G_and_S_cingul-Mid-Post'	'lh.G_cingul-Post-dorsal'	'lh.G_cingul-Post-ventral'	'lh.G_cuneus'	'lh.G_front_inf-Opercular'	'lh.G_front_inf-Orbital'	'lh.G_front_inf-Triangul'	'lh.G_front_middle'	'lh.G_front_sup'	'lh.G_Ins_lg_and_S_cent_ins'	'lh.G_insular_short'	'lh.G_occipital_middle'	'lh.G_occipital_sup'	'lh.G_oc-temp_lat-fusifor'	'lh.G_oc-temp_med-Lingual'	'lh.G_oc-temp_med-Parahip'	'lh.G_orbital'	'lh.G_pariet_inf-Angular'	'lh.G_pariet_inf-Supramar'	'lh.G_parietal_sup'	'lh.G_postcentral'	'lh.G_precentral'	'lh.G_precuneus'	'lh.G_rectus'	'lh.G_subcallosal'	'lh.G_temp_sup-G_T_transv'	'lh.G_temp_sup-Lateral'	'lh.G_temp_sup-Plan_polar'	'lh.G_temp_sup-Plan_tempo'	'lh.G_temporal_inf'	'lh.G_temporal_middle'	'lh.Lat_Fis-ant-Horizont'	'lh.Lat_Fis-ant-Vertical'	'lh.Lat_Fis-post'	'lh.Medial_wall'	'lh.Pole_occipital'	'lh.Pole_temporal'	'lh.S_calcarine'	'lh.S_central'	'lh.S_cingul-Marginalis'	'lh.S_circular_insula_ant'	'lh.S_circular_insula_inf'	'lh.S_circular_insula_sup'	'lh.S_collat_transv_ant'	'lh.S_collat_transv_post'	'lh.S_front_inf'	'lh.S_front_middle'	'lh.S_front_sup'	'lh.S_interm_prim-Jensen'	'lh.S_intrapariet_and_P_trans'	'lh.S_oc_middle_and_Lunatus'	'lh.S_oc_sup_and_transversal'	'lh.S_occipital_ant'	'lh.S_oc-temp_lat'	'lh.S_oc-temp_med_and_Lingual'	'lh.S_orbital_lateral'	'lh.S_orbital_med-olfact'	'lh.S_orbital-H_Shaped'	'lh.S_parieto_occipital'	'lh.S_pericallosal'	'lh.S_postcentral'	'lh.S_precentral-inf-part'	'lh.S_precentral-sup-part'	'lh.S_suborbital'	'lh.S_subparietal'	'lh.S_temporal_inf'	'lh.S_temporal_sup'	'lh.S_temporal_transverse'	'rh.Unknown'	'rh.G_and_S_frontomargin'	'rh.G_and_S_occipital_inf'	'rh.G_and_S_paracentral'	'rh.G_and_S_subcentral'	'rh.G_and_S_transv_frontopol'	'rh.G_and_S_cingul-Ant'	'rh.G_and_S_cingul-Mid-Ant'	'rh.G_and_S_cingul-Mid-Post'	'rh.G_cingul-Post-dorsal'	'rh.G_cingul-Post-ventral'	'rh.G_cuneus'	'rh.G_front_inf-Opercular'	'rh.G_front_inf-Orbital'	'rh.G_front_inf-Triangul'	'rh.G_front_middle'	'rh.G_front_sup'	'rh.G_Ins_lg_and_S_cent_ins'	'rh.G_insular_short'	'rh.G_occipital_middle'	'rh.G_occipital_sup'	'rh.G_oc-temp_lat-fusifor'	'rh.G_oc-temp_med-Lingual'	'rh.G_oc-temp_med-Parahip'	'rh.G_orbital'	'rh.G_pariet_inf-Angular'	'rh.G_pariet_inf-Supramar'	'rh.G_parietal_sup'	'rh.G_postcentral'	'rh.G_precentral'	'rh.G_precuneus'	'rh.G_rectus'	'rh.G_subcallosal'	'rh.G_temp_sup-G_T_transv'	'rh.G_temp_sup-Lateral'	'rh.G_temp_sup-Plan_polar'	'rh.G_temp_sup-Plan_tempo'	'rh.G_temporal_inf'	'rh.G_temporal_middle'	'rh.Lat_Fis-ant-Horizont'	'rh.Lat_Fis-ant-Vertical'	'rh.Lat_Fis-post'	'rh.Medial_wall'	'rh.Pole_occipital'	'rh.Pole_temporal'	'rh.S_calcarine'	'rh.S_central'	'rh.S_cingul-Marginalis'	'rh.S_circular_insula_ant'	'rh.S_circular_insula_inf'	'rh.S_circular_insula_sup'	'rh.S_collat_transv_ant'	'rh.S_collat_transv_post'	'rh.S_front_inf'	'rh.S_front_middle'	'rh.S_front_sup'	'rh.S_interm_prim-Jensen'	'rh.S_intrapariet_and_P_trans'	'rh.S_oc_middle_and_Lunatus'	'rh.S_oc_sup_and_transversal'	'rh.S_occipital_ant'	'rh.S_oc-temp_lat'	'rh.S_oc-temp_med_and_Lingual'	'rh.S_orbital_lateral'	'rh.S_orbital_med-olfact'	'rh.S_orbital-H_Shaped'	'rh.S_parieto_occipital'	'rh.S_pericallosal'	'rh.S_postcentral'	'rh.S_precentral-inf-part'	'rh.S_precentral-sup-part'	'rh.S_suborbital'	'rh.S_subparietal'	'rh.S_temporal_inf'	'rh.S_temporal_sup'	'rh.S_temporal_transverse'};
                    app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.ItemsData{1};
                case 'HCP_MMP1'
                    app.DropDown_RegionLabel.Items = {'自定义' 'lh.???'	'lh.V1'	'lh.MST'	'lh.V6'	'lh.V2'	'lh.V3'	'lh.V4'	'lh.V8'	'lh.4'	'lh.3b'	'lh.FEF'	'lh.PEF'	'lh.55b'	'lh.V3A'	'lh.RSC'	'lh.POS2'	'lh.V7'	'lh.IPS1'	'lh.FFC'	'lh.V3B'	'lh.LO1'	'lh.LO2'	'lh.PIT'	'lh.MT'	'lh.A1'	'lh.PSL'	'lh.SFL'	'lh.PCV'	'lh.STV'	'lh.7Pm'	'lh.7m'	'lh.POS1'	'lh.23d'	'lh.v23ab'	'lh.d23ab'	'lh.31pv'	'lh.5m'	'lh.5mv'	'lh.23c'	'lh.5L'	'lh.24dd'	'lh.24dv'	'lh.7AL'	'lh.SCEF'	'lh.6ma'	'lh.7Am'	'lh.7PL'	'lh.7PC'	'lh.LIPv'	'lh.VIP'	'lh.MIP'	'lh.1'	'lh.2'	'lh.3a'	'lh.6d'	'lh.6mp'	'lh.6v'	'lh.p24pr'	'lh.33pr'	'lh.a24pr'	'lh.p32pr'	'lh.a24'	'lh.d32'	'lh.8BM'	'lh.p32'	'lh.10r'	'lh.47m'	'lh.8Av'	'lh.8Ad'	'lh.9m'	'lh.8BL'	'lh.9p'	'lh.10d'	'lh.8C'	'lh.44'	'lh.45'	'lh.47l'	'lh.a47r'	'lh.6r'	'lh.IFJa'	'lh.IFJp'	'lh.IFSp'	'lh.IFSa'	'lh.p9-46v'	'lh.46'	'lh.a9-46v'	'lh.9-46d'	'lh.9a'	'lh.10v'	'lh.a10p'	'lh.10pp'	'lh.11l'	'lh.13l'	'lh.OFC'	'lh.47s'	'lh.LIPd'	'lh.6a'	'lh.i6-8'	'lh.s6-8'	'lh.43'	'lh.OP4'	'lh.OP1'	'lh.OP2-3'	'lh.52'	'lh.RI'	'lh.PFcm'	'lh.PoI2'	'lh.TA2'	'lh.FOP4'	'lh.MI'	'lh.Pir'	'lh.AVI'	'lh.AAIC'	'lh.FOP1'	'lh.FOP3'	'lh.FOP2'	'lh.PFt'	'lh.AIP'	'lh.EC'	'lh.PreS'	'lh.H'	'lh.ProS'	'lh.PeEc'	'lh.STGa'	'lh.PBelt'	'lh.A5'	'lh.PHA1'	'lh.PHA3'	'lh.STSda'	'lh.STSdp'	'lh.STSvp'	'lh.TGd'	'lh.TE1a'	'lh.TE1p'	'lh.TE2a'	'lh.TF'	'lh.TE2p'	'lh.PHT'	'lh.PH'	'lh.TPOJ1'	'lh.TPOJ2'	'lh.TPOJ3'	'lh.DVT'	'lh.PGp'	'lh.IP2'	'lh.IP1'	'lh.IP0'	'lh.PFop'	'lh.PF'	'lh.PFm'	'lh.PGi'	'lh.PGs'	'lh.V6A'	'lh.VMV1'	'lh.VMV3'	'lh.PHA2'	'lh.V4t'	'lh.FST'	'lh.V3CD'	'lh.LO3'	'lh.VMV2'	'lh.31pd'	'lh.31a'	'lh.VVC'	'lh.25'	'lh.s32'	'lh.pOFC'	'lh.PoI1'	'lh.Ig'	'lh.FOP5'	'lh.p10p'	'lh.p47r'	'lh.TGv'	'lh.MBelt'	'lh.LBelt'	'lh.A4'	'lh.STSva'	'lh.TE1m'	'lh.PI'	'lh.a32pr'	'lh.p24'	'rh.???'	'rh.V1'	'rh.MST'	'rh.V6'	'rh.V2'	'rh.V3'	'rh.V4'	'rh.V8'	'rh.4'	'rh.3b'	'rh.FEF'	'rh.PEF'	'rh.55b'	'rh.V3A'	'rh.RSC'	'rh.POS2'	'rh.V7'	'rh.IPS1'	'rh.FFC'	'rh.V3B'	'rh.LO1'	'rh.LO2'	'rh.PIT'	'rh.MT'	'rh.A1'	'rh.PSL'	'rh.SFL'	'rh.PCV'	'rh.STV'	'rh.7Pm'	'rh.7m'	'rh.POS1'	'rh.23d'	'rh.v23ab'	'rh.d23ab'	'rh.31pv'	'rh.5m'	'rh.5mv'	'rh.23c'	'rh.5L'	'rh.24dd'	'rh.24dv'	'rh.7AL'	'rh.SCEF'	'rh.6ma'	'rh.7Am'	'rh.7PL'	'rh.7PC'	'rh.LIPv'	'rh.VIP'	'rh.MIP'	'rh.1'	'rh.2'	'rh.3a'	'rh.6d'	'rh.6mp'	'rh.6v'	'rh.p24pr'	'rh.33pr'	'rh.a24pr'	'rh.p32pr'	'rh.a24'	'rh.d32'	'rh.8BM'	'rh.p32'	'rh.10r'	'rh.47m'	'rh.8Av'	'rh.8Ad'	'rh.9m'	'rh.8BL'	'rh.9p'	'rh.10d'	'rh.8C'	'rh.44'	'rh.45'	'rh.47l'	'rh.a47r'	'rh.6r'	'rh.IFJa'	'rh.IFJp'	'rh.IFSp'	'rh.IFSa'	'rh.p9-46v'	'rh.46'	'rh.a9-46v'	'rh.9-46d'	'rh.9a'	'rh.10v'	'rh.a10p'	'rh.10pp'	'rh.11l'	'rh.13l'	'rh.OFC'	'rh.47s'	'rh.LIPd'	'rh.6a'	'rh.i6-8'	'rh.s6-8'	'rh.43'	'rh.OP4'	'rh.OP1'	'rh.OP2-3'	'rh.52'	'rh.RI'	'rh.PFcm'	'rh.PoI2'	'rh.TA2'	'rh.FOP4'	'rh.MI'	'rh.Pir'	'rh.AVI'	'rh.AAIC'	'rh.FOP1'	'rh.FOP3'	'rh.FOP2'	'rh.PFt'	'rh.AIP'	'rh.EC'	'rh.PreS'	'rh.H'	'rh.ProS'	'rh.PeEc'	'rh.STGa'	'rh.PBelt'	'rh.A5'	'rh.PHA1'	'rh.PHA3'	'rh.STSda'	'rh.STSdp'	'rh.STSvp'	'rh.TGd'	'rh.TE1a'	'rh.TE1p'	'rh.TE2a'	'rh.TF'	'rh.TE2p'	'rh.PHT'	'rh.PH'	'rh.TPOJ1'	'rh.TPOJ2'	'rh.TPOJ3'	'rh.DVT'	'rh.PGp'	'rh.IP2'	'rh.IP1'	'rh.IP0'	'rh.PFop'	'rh.PF'	'rh.PFm'	'rh.PGi'	'rh.PGs'	'rh.V6A'	'rh.VMV1'	'rh.VMV3'	'rh.PHA2'	'rh.V4t'	'rh.FST'	'rh.V3CD'	'rh.LO3'	'rh.VMV2'	'rh.31pd'	'rh.31a'	'rh.VVC'	'rh.25'	'rh.s32'	'rh.pOFC'	'rh.PoI1'	'rh.Ig'	'rh.FOP5'	'rh.p10p'	'rh.p47r'	'rh.TGv'	'rh.MBelt'	'rh.LBelt'	'rh.A4'	'rh.STSva'	'rh.TE1m'	'rh.PI'	'rh.a32pr'	'rh.p24'};
                    app.DropDown_RegionLabel.ItemsData = {'自定义' 'lh.???'	'lh.V1'	'lh.MST'	'lh.V6'	'lh.V2'	'lh.V3'	'lh.V4'	'lh.V8'	'lh.4'	'lh.3b'	'lh.FEF'	'lh.PEF'	'lh.55b'	'lh.V3A'	'lh.RSC'	'lh.POS2'	'lh.V7'	'lh.IPS1'	'lh.FFC'	'lh.V3B'	'lh.LO1'	'lh.LO2'	'lh.PIT'	'lh.MT'	'lh.A1'	'lh.PSL'	'lh.SFL'	'lh.PCV'	'lh.STV'	'lh.7Pm'	'lh.7m'	'lh.POS1'	'lh.23d'	'lh.v23ab'	'lh.d23ab'	'lh.31pv'	'lh.5m'	'lh.5mv'	'lh.23c'	'lh.5L'	'lh.24dd'	'lh.24dv'	'lh.7AL'	'lh.SCEF'	'lh.6ma'	'lh.7Am'	'lh.7PL'	'lh.7PC'	'lh.LIPv'	'lh.VIP'	'lh.MIP'	'lh.1'	'lh.2'	'lh.3a'	'lh.6d'	'lh.6mp'	'lh.6v'	'lh.p24pr'	'lh.33pr'	'lh.a24pr'	'lh.p32pr'	'lh.a24'	'lh.d32'	'lh.8BM'	'lh.p32'	'lh.10r'	'lh.47m'	'lh.8Av'	'lh.8Ad'	'lh.9m'	'lh.8BL'	'lh.9p'	'lh.10d'	'lh.8C'	'lh.44'	'lh.45'	'lh.47l'	'lh.a47r'	'lh.6r'	'lh.IFJa'	'lh.IFJp'	'lh.IFSp'	'lh.IFSa'	'lh.p9-46v'	'lh.46'	'lh.a9-46v'	'lh.9-46d'	'lh.9a'	'lh.10v'	'lh.a10p'	'lh.10pp'	'lh.11l'	'lh.13l'	'lh.OFC'	'lh.47s'	'lh.LIPd'	'lh.6a'	'lh.i6-8'	'lh.s6-8'	'lh.43'	'lh.OP4'	'lh.OP1'	'lh.OP2-3'	'lh.52'	'lh.RI'	'lh.PFcm'	'lh.PoI2'	'lh.TA2'	'lh.FOP4'	'lh.MI'	'lh.Pir'	'lh.AVI'	'lh.AAIC'	'lh.FOP1'	'lh.FOP3'	'lh.FOP2'	'lh.PFt'	'lh.AIP'	'lh.EC'	'lh.PreS'	'lh.H'	'lh.ProS'	'lh.PeEc'	'lh.STGa'	'lh.PBelt'	'lh.A5'	'lh.PHA1'	'lh.PHA3'	'lh.STSda'	'lh.STSdp'	'lh.STSvp'	'lh.TGd'	'lh.TE1a'	'lh.TE1p'	'lh.TE2a'	'lh.TF'	'lh.TE2p'	'lh.PHT'	'lh.PH'	'lh.TPOJ1'	'lh.TPOJ2'	'lh.TPOJ3'	'lh.DVT'	'lh.PGp'	'lh.IP2'	'lh.IP1'	'lh.IP0'	'lh.PFop'	'lh.PF'	'lh.PFm'	'lh.PGi'	'lh.PGs'	'lh.V6A'	'lh.VMV1'	'lh.VMV3'	'lh.PHA2'	'lh.V4t'	'lh.FST'	'lh.V3CD'	'lh.LO3'	'lh.VMV2'	'lh.31pd'	'lh.31a'	'lh.VVC'	'lh.25'	'lh.s32'	'lh.pOFC'	'lh.PoI1'	'lh.Ig'	'lh.FOP5'	'lh.p10p'	'lh.p47r'	'lh.TGv'	'lh.MBelt'	'lh.LBelt'	'lh.A4'	'lh.STSva'	'lh.TE1m'	'lh.PI'	'lh.a32pr'	'lh.p24'	'rh.???'	'rh.V1'	'rh.MST'	'rh.V6'	'rh.V2'	'rh.V3'	'rh.V4'	'rh.V8'	'rh.4'	'rh.3b'	'rh.FEF'	'rh.PEF'	'rh.55b'	'rh.V3A'	'rh.RSC'	'rh.POS2'	'rh.V7'	'rh.IPS1'	'rh.FFC'	'rh.V3B'	'rh.LO1'	'rh.LO2'	'rh.PIT'	'rh.MT'	'rh.A1'	'rh.PSL'	'rh.SFL'	'rh.PCV'	'rh.STV'	'rh.7Pm'	'rh.7m'	'rh.POS1'	'rh.23d'	'rh.v23ab'	'rh.d23ab'	'rh.31pv'	'rh.5m'	'rh.5mv'	'rh.23c'	'rh.5L'	'rh.24dd'	'rh.24dv'	'rh.7AL'	'rh.SCEF'	'rh.6ma'	'rh.7Am'	'rh.7PL'	'rh.7PC'	'rh.LIPv'	'rh.VIP'	'rh.MIP'	'rh.1'	'rh.2'	'rh.3a'	'rh.6d'	'rh.6mp'	'rh.6v'	'rh.p24pr'	'rh.33pr'	'rh.a24pr'	'rh.p32pr'	'rh.a24'	'rh.d32'	'rh.8BM'	'rh.p32'	'rh.10r'	'rh.47m'	'rh.8Av'	'rh.8Ad'	'rh.9m'	'rh.8BL'	'rh.9p'	'rh.10d'	'rh.8C'	'rh.44'	'rh.45'	'rh.47l'	'rh.a47r'	'rh.6r'	'rh.IFJa'	'rh.IFJp'	'rh.IFSp'	'rh.IFSa'	'rh.p9-46v'	'rh.46'	'rh.a9-46v'	'rh.9-46d'	'rh.9a'	'rh.10v'	'rh.a10p'	'rh.10pp'	'rh.11l'	'rh.13l'	'rh.OFC'	'rh.47s'	'rh.LIPd'	'rh.6a'	'rh.i6-8'	'rh.s6-8'	'rh.43'	'rh.OP4'	'rh.OP1'	'rh.OP2-3'	'rh.52'	'rh.RI'	'rh.PFcm'	'rh.PoI2'	'rh.TA2'	'rh.FOP4'	'rh.MI'	'rh.Pir'	'rh.AVI'	'rh.AAIC'	'rh.FOP1'	'rh.FOP3'	'rh.FOP2'	'rh.PFt'	'rh.AIP'	'rh.EC'	'rh.PreS'	'rh.H'	'rh.ProS'	'rh.PeEc'	'rh.STGa'	'rh.PBelt'	'rh.A5'	'rh.PHA1'	'rh.PHA3'	'rh.STSda'	'rh.STSdp'	'rh.STSvp'	'rh.TGd'	'rh.TE1a'	'rh.TE1p'	'rh.TE2a'	'rh.TF'	'rh.TE2p'	'rh.PHT'	'rh.PH'	'rh.TPOJ1'	'rh.TPOJ2'	'rh.TPOJ3'	'rh.DVT'	'rh.PGp'	'rh.IP2'	'rh.IP1'	'rh.IP0'	'rh.PFop'	'rh.PF'	'rh.PFm'	'rh.PGi'	'rh.PGs'	'rh.V6A'	'rh.VMV1'	'rh.VMV3'	'rh.PHA2'	'rh.V4t'	'rh.FST'	'rh.V3CD'	'rh.LO3'	'rh.VMV2'	'rh.31pd'	'rh.31a'	'rh.VVC'	'rh.25'	'rh.s32'	'rh.pOFC'	'rh.PoI1'	'rh.Ig'	'rh.FOP5'	'rh.p10p'	'rh.p47r'	'rh.TGv'	'rh.MBelt'	'rh.LBelt'	'rh.A4'	'rh.STSva'	'rh.TE1m'	'rh.PI'	'rh.a32pr'	'rh.p24'};
                    app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.ItemsData{1};
                case 'AAL3'
                    app.atlasDir = fullfile(app.toolboxDir,'ATLAS','AAL3');
                    file = fullfile(app.atlasDir,'AAL3v1_1mm.nii.txt');
                    app.atlasTable = readtable(file,'range','A1:B170');
                    app.atlasTable.Properties.VariableNames = {'Index', 'Name'};
                    app.DropDown_RegionLabel.Items = ['Undefined'; app.atlasTable.Name];
                    app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.Items{1};
                case 'Undefined'
                    app.atlasDir = [];
                    app.atlasTable = [];
                    app.DropDown_RegionLabel.Items = {'Undefined'};
                    app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.Items{1};
            end
        end

        % Value changed function: DropDown_RegionLabel
        function DropDown_RegionLabelValueChanged(app, event)
            app.regionName = app.DropDown_RegionLabel.Value;
            if ~isempty(app.regionName)
                % Get the center MNI coord of the ROI
                switch app.atlas
                    case 'AAL3'
                        idx = find(strcmp(app.regionName,app.atlasTable.Name));
                        app.coordMNI = mean(AAL3ROI(app.atlasDir,idx),1);
                end
            end
            app.EditField_Name.Value = app.regionName;
            app.XEditField.Value = app.coordMNI(1);
            app.YEditField.Value = app.coordMNI(2);
            app.ZEditField.Value = app.coordMNI(3);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.coordMNI(1) = app.XEditField.Value;
            app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
            app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.ItemsData{1};
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.coordMNI(2) = app.YEditField.Value;
            app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
            app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.ItemsData{1};
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.coordMNI(3) = app.ZEditField.Value;
            app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
            app.DropDown_RegionLabel.Value = app.DropDown_RegionLabel.ItemsData{1};
        end

        % Value changed function: EditField_r
        function EditField_rValueChanged(app, event)
            app.r = app.EditField_r.Value;
        end

        % Value changed function: EditField_Name
        function EditField_NameValueChanged(app, event)
            app.regionName = app.EditField_Name.Value;
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            updateTarget(app.mainApp,app.regionName,app.coordMNI,app.r,app.ShapeType);
            delete(app);
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            delete(app);
        end

        % Selection changed function: ShapeButtonGroup
        function ShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShapeButtonGroup.SelectedObject;
            switch selectedButton.Value
                case 0
                    app.ShapeType = 'Sphere';
                case 1
                    app.ShapeType = 'Default';
                    app.r = nan;    
                    app.EditField_r.Value
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_DialogTarget = uifigure('Visible', 'off');
            app.UI_DialogTarget.Name = 'MATLAB App';
            app.Grid_DialogTarget = uigridlayout(app.UI_DialogTarget);
            app.Panel_Target = uipanel(app.Grid_DialogTarget);
            app.Panel_Target.Title = 'Target brain region (MNI space)';
            app.Grid_Target = uigridlayout(app.Panel_Target);
            app.mmLabel_2 = uilabel(app.Grid_Target);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel = uilabel(app.Grid_Target);
            app.mmLabel.Text = 'mm';
            app.AtlasLabel = uilabel(app.Grid_Target);
            app.AtlasLabel.Text = 'Atlas';
            app.Label_3 = uilabel(app.Grid_Target);
            app.RadiusLabel = uilabel(app.Grid_Target);
            app.RadiusLabel.Text = 'Radius';
            app.EditField_r = uieditfield(app.Grid_Target, 'numeric');
            app.EditField_r.ValueChangedFcn = createCallbackFcn(app, @EditField_rValueChanged, true);
            app.EditField_r.Value = 10;
            app.Button_OK = uibutton(app.Grid_Target, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Text = 'OK';
            app.Button_Cancel = uibutton(app.Grid_Target, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = 'Cancel';
            app.XEditField = uieditfield(app.Grid_Target, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField = uieditfield(app.Grid_Target, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditField = uieditfield(app.Grid_Target, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.XLabel = uilabel(app.Grid_Target);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Grid_Target);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.Grid_Target);
            app.ZLabel.Text = 'Z';
            app.ShapeLabel = uilabel(app.Grid_Target);
            app.ShapeLabel.Text = 'Shape';
            app.ShapeButtonGroup = uibuttongroup(app.Grid_Target);
            app.ShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShapeButtonGroupSelectionChanged, true);
            app.DefaultButton = uiradiobutton(app.ShapeButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.SphereButton = uiradiobutton(app.ShapeButtonGroup);
            app.SphereButton.Text = 'Sphere';
            app.SphereButton.Value = true;
            app.CenterLabel = uilabel(app.Grid_Target);
            app.CenterLabel.Text = 'Center';
            app.DropDown_Atlas = uidropdown(app.Grid_Target);
            app.DropDown_Atlas.Items = {};
            app.DropDown_Atlas.ValueChangedFcn = createCallbackFcn(app, @DropDown_AtlasValueChanged, true);
            app.DropDown_Atlas.Value = {};
            app.EditField_Name = uieditfield(app.Grid_Target, 'text');
            app.EditField_Name.ValueChangedFcn = createCallbackFcn(app, @EditField_NameValueChanged, true);
            app.Label_Name = uilabel(app.Grid_Target);
            app.Label_Name.Text = 'Name';
            app.DropDown_RegionLabel = uidropdown(app.Grid_Target);
            app.DropDown_RegionLabel.Items = {};
            app.DropDown_RegionLabel.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegionLabelValueChanged, true);
            app.DropDown_RegionLabel.Value = {};
            % Show the figure after all components are created
            app.UI_DialogTarget.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Target_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_DialogTarget)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_DialogTarget)
        end
    end
end