classdef TetLeadfieldQuery_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GraymattermiddlesurfacePanel  matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp; % Description
        status = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainApp = mainapp;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            try
                SIMNIBS_LF_tet(app.mainApp.dataRoot,app.mainApp.subMark);
                app.status = true;
            catch ME
                app.status = false;
            end    
            update_TetLeadfieldQuery(app.mainApp,app.status);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.status = false;
            update_TetLeadfieldQuery(app.mainApp,app.status);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraymattermiddlesurfacePanel = uipanel(app.UIFigure);
            app.GraymattermiddlesurfacePanel.Title = 'Gray matter middle surface';
            app.OKButton = uibutton(app.GraymattermiddlesurfacePanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GraymattermiddlesurfacePanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Label = uilabel(app.GraymattermiddlesurfacePanel);
            app.Label.Text = 'Leadfield file for whole brain not existed!  Would you like to run simNIBS to get it?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TetLeadfieldQuery_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end