classdef WMntQuery_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HeadrecoPanel       matlab.ui.container.Panel
        CancelButton        matlab.ui.control.Button
        Label               matlab.ui.control.Label
        ACIDpipelineButton  matlab.ui.control.Button
        ReadfromFSLButton   matlab.ui.control.Button
    end


    properties (Access = private)
        status = false;
    end

    properties (Access = public)
        mainApp; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainApp = mainapp;
        end

        % Button pushed function: ReadfromFSLButton
        function ReadfromFSLButtonPushed(app, event)
            try
                WMNT(app.mainApp.dataRoot,app.mainApp.subMark,1);
                app.status = true;
            catch ME
                app.status = false;
            end
            update_WMntQuery(app.mainApp,app.status);
            delete(app);
        end

        % Button pushed function: ACIDpipelineButton
        function ACIDpipelineButtonPushed(app, event)
            try
                WMNT(app.mainApp.dataRoot,app.mainApp.subMark,2);
                app.status = true;
            catch ME
                app.status = false;
            end
            update_HeadrecoQuery(app.mainApp,app.status);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.status = false;
            update_HeadrecoQuery(app.mainApp,app.status);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeadrecoPanel = uipanel(app.UIFigure);
            app.HeadrecoPanel.Title = 'Headreco ';
            app.ReadfromFSLButton = uibutton(app.HeadrecoPanel, 'push');
            app.ReadfromFSLButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfromFSLButtonPushed, true);
            app.ReadfromFSLButton.Text = 'Read from FSL';
            app.ACIDpipelineButton = uibutton(app.HeadrecoPanel, 'push');
            app.ACIDpipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ACIDpipelineButtonPushed, true);
            app.ACIDpipelineButton.Text = 'ACID pipeline';
            app.Label = uilabel(app.HeadrecoPanel);
            app.Label.Text = 'Orientation file for white matter not existed! Would you like to get it?';
            app.CancelButton = uibutton(app.HeadrecoPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WMntQuery_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end