classdef Dialog_ROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_DialogROI         matlab.ui.Figure
        Grid_DialogROI       matlab.ui.container.GridLayout
        Panel_ROI            matlab.ui.container.Panel
        Grid_ROI             matlab.ui.container.GridLayout
        Label                matlab.ui.control.Label
        DropDown_Atlas       matlab.ui.control.DropDown
        DropDown_2Label      matlab.ui.control.Label
        DropDown_RegionName  matlab.ui.control.DropDown
        Label_2              matlab.ui.control.Label
        EditField_r          matlab.ui.control.NumericEditField
        Button_OK            matlab.ui.control.Button
        Button_Cancel        matlab.ui.control.Button
        XEditField           matlab.ui.control.NumericEditField
        YEditField           matlab.ui.control.NumericEditField
        ZEditField           matlab.ui.control.NumericEditField
        XLabel               matlab.ui.control.Label
        YLabel               matlab.ui.control.Label
        ZLabel               matlab.ui.control.Label
        EditField_label      matlab.ui.control.EditField
        Label_label          matlab.ui.control.Label
        ContextMenu          matlab.ui.container.ContextMenu
        mmMenu_r             matlab.ui.container.Menu
        ContextMenu2         matlab.ui.container.ContextMenu
        Menu_label           matlab.ui.container.Menu
        ContextMenu3         matlab.ui.container.ContextMenu
        MNIMenu_X            matlab.ui.container.Menu
        ContextMenu4         matlab.ui.container.ContextMenu
        MNIMenu_Y            matlab.ui.container.Menu
        ContextMenu5         matlab.ui.container.ContextMenu
        MNIMenu_Z            matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp; % The main app
        inputpath;
        outputpath;
        subMark;
        
        eletype = 'tri';
        atlas = '无';
        regionname = '自定义';
        coordMNI = [0 0 0];
        r = 10;
        label = ' ';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, InputPath, OutputPath, Eletype)
            % Store main app object
            app.CallingApp = mainapp;
            app.inputpath = InputPath;
            app.outputpath = OutputPath;
            [~,app.subMark] = fileparts(app.inputpath);
            app.eletype = Eletype;
            switch app.eletype
                case 'tri'
                    app.DropDown_Atlas.Items = {'无','DK40','a2009s','HCP_MMP1'};
                    app.DropDown_Atlas.ItemsData = {'无','DK40','a2009s','HCP_MMP1'};
                    app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
                case 'tet'
                    app.DropDown_Atlas.Items = {'无','AAL'};
                    app.DropDown_Atlas.ItemsData = {'无','AAL'};
                    app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};   
            end
        end

        % Value changed function: DropDown_Atlas
        function DropDown_AtlasValueChanged(app, event)
            value = app.DropDown_Atlas.Value;
            if ~isempty(value)
                app.atlas = app.DropDown_Atlas.Value;
                switch app.atlas
                    case 'DK40'
                        app.DropDown_RegionName.Items = {'自定义' 'lh.unknown'	'lh.bankssts'	'lh.caudalanteriorcingulate'	'lh.caudalmiddlefrontal'	'lh.corpuscallosum'	'lh.cuneus'	'lh.entorhinal'	'lh.fusiform'	'lh.inferiorparietal'	'lh.inferiortemporal'	'lh.isthmuscingulate'	'lh.lateraloccipital'	'lh.lateralorbitofrontal'	'lh.lingual'	'lh.medialorbitofrontal'	'lh.middletemporal'	'lh.parahippocampal'	'lh.paracentral'	'lh.parsopercularis'	'lh.parsorbitalis'	'lh.parstriangularis'	'lh.pericalcarine'	'lh.postcentral'	'lh.posteriorcingulate'	'lh.precentral'	'lh.precuneus'	'lh.rostralanteriorcingulate'	'lh.rostralmiddlefrontal'	'lh.superiorfrontal'	'lh.superiorparietal'	'lh.superiortemporal'	'lh.supramarginal'	'lh.frontalpole'	'lh.temporalpole'	'lh.transversetemporal'	'lh.insula'	'rh.unknown'	'rh.bankssts'	'rh.caudalanteriorcingulate'	'rh.caudalmiddlefrontal'	'rh.corpuscallosum'	'rh.cuneus'	'rh.entorhinal'	'rh.fusiform'	'rh.inferiorparietal'	'rh.inferiortemporal'	'rh.isthmuscingulate'	'rh.lateraloccipital'	'rh.lateralorbitofrontal'	'rh.lingual'	'rh.medialorbitofrontal'	'rh.middletemporal'	'rh.parahippocampal'	'rh.paracentral'	'rh.parsopercularis'	'rh.parsorbitalis'	'rh.parstriangularis'	'rh.pericalcarine'	'rh.postcentral'	'rh.posteriorcingulate'	'rh.precentral'	'rh.precuneus'	'rh.rostralanteriorcingulate'	'rh.rostralmiddlefrontal'	'rh.superiorfrontal'	'rh.superiorparietal'	'rh.superiortemporal'	'rh.supramarginal'	'rh.frontalpole'	'rh.temporalpole'	'rh.transversetemporal'	'rh.insula'};
                        app.DropDown_RegionName.ItemsData = {'自定义' 'lh.unknown'	'lh.bankssts'	'lh.caudalanteriorcingulate'	'lh.caudalmiddlefrontal'	'lh.corpuscallosum'	'lh.cuneus'	'lh.entorhinal'	'lh.fusiform'	'lh.inferiorparietal'	'lh.inferiortemporal'	'lh.isthmuscingulate'	'lh.lateraloccipital'	'lh.lateralorbitofrontal'	'lh.lingual'	'lh.medialorbitofrontal'	'lh.middletemporal'	'lh.parahippocampal'	'lh.paracentral'	'lh.parsopercularis'	'lh.parsorbitalis'	'lh.parstriangularis'	'lh.pericalcarine'	'lh.postcentral'	'lh.posteriorcingulate'	'lh.precentral'	'lh.precuneus'	'lh.rostralanteriorcingulate'	'lh.rostralmiddlefrontal'	'lh.superiorfrontal'	'lh.superiorparietal'	'lh.superiortemporal'	'lh.supramarginal'	'lh.frontalpole'	'lh.temporalpole'	'lh.transversetemporal'	'lh.insula'	'rh.unknown'	'rh.bankssts'	'rh.caudalanteriorcingulate'	'rh.caudalmiddlefrontal'	'rh.corpuscallosum'	'rh.cuneus'	'rh.entorhinal'	'rh.fusiform'	'rh.inferiorparietal'	'rh.inferiortemporal'	'rh.isthmuscingulate'	'rh.lateraloccipital'	'rh.lateralorbitofrontal'	'rh.lingual'	'rh.medialorbitofrontal'	'rh.middletemporal'	'rh.parahippocampal'	'rh.paracentral'	'rh.parsopercularis'	'rh.parsorbitalis'	'rh.parstriangularis'	'rh.pericalcarine'	'rh.postcentral'	'rh.posteriorcingulate'	'rh.precentral'	'rh.precuneus'	'rh.rostralanteriorcingulate'	'rh.rostralmiddlefrontal'	'rh.superiorfrontal'	'rh.superiorparietal'	'rh.superiortemporal'	'rh.supramarginal'	'rh.frontalpole'	'rh.temporalpole'	'rh.transversetemporal'	'rh.insula'};
                        app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
                    case 'a2009s'
                        app.DropDown_RegionName.Items = {'自定义' 'lh.Unknown'	'lh.G_and_S_frontomargin'	'lh.G_and_S_occipital_inf'	'lh.G_and_S_paracentral'	'lh.G_and_S_subcentral'	'lh.G_and_S_transv_frontopol'	'lh.G_and_S_cingul-Ant'	'lh.G_and_S_cingul-Mid-Ant'	'lh.G_and_S_cingul-Mid-Post'	'lh.G_cingul-Post-dorsal'	'lh.G_cingul-Post-ventral'	'lh.G_cuneus'	'lh.G_front_inf-Opercular'	'lh.G_front_inf-Orbital'	'lh.G_front_inf-Triangul'	'lh.G_front_middle'	'lh.G_front_sup'	'lh.G_Ins_lg_and_S_cent_ins'	'lh.G_insular_short'	'lh.G_occipital_middle'	'lh.G_occipital_sup'	'lh.G_oc-temp_lat-fusifor'	'lh.G_oc-temp_med-Lingual'	'lh.G_oc-temp_med-Parahip'	'lh.G_orbital'	'lh.G_pariet_inf-Angular'	'lh.G_pariet_inf-Supramar'	'lh.G_parietal_sup'	'lh.G_postcentral'	'lh.G_precentral'	'lh.G_precuneus'	'lh.G_rectus'	'lh.G_subcallosal'	'lh.G_temp_sup-G_T_transv'	'lh.G_temp_sup-Lateral'	'lh.G_temp_sup-Plan_polar'	'lh.G_temp_sup-Plan_tempo'	'lh.G_temporal_inf'	'lh.G_temporal_middle'	'lh.Lat_Fis-ant-Horizont'	'lh.Lat_Fis-ant-Vertical'	'lh.Lat_Fis-post'	'lh.Medial_wall'	'lh.Pole_occipital'	'lh.Pole_temporal'	'lh.S_calcarine'	'lh.S_central'	'lh.S_cingul-Marginalis'	'lh.S_circular_insula_ant'	'lh.S_circular_insula_inf'	'lh.S_circular_insula_sup'	'lh.S_collat_transv_ant'	'lh.S_collat_transv_post'	'lh.S_front_inf'	'lh.S_front_middle'	'lh.S_front_sup'	'lh.S_interm_prim-Jensen'	'lh.S_intrapariet_and_P_trans'	'lh.S_oc_middle_and_Lunatus'	'lh.S_oc_sup_and_transversal'	'lh.S_occipital_ant'	'lh.S_oc-temp_lat'	'lh.S_oc-temp_med_and_Lingual'	'lh.S_orbital_lateral'	'lh.S_orbital_med-olfact'	'lh.S_orbital-H_Shaped'	'lh.S_parieto_occipital'	'lh.S_pericallosal'	'lh.S_postcentral'	'lh.S_precentral-inf-part'	'lh.S_precentral-sup-part'	'lh.S_suborbital'	'lh.S_subparietal'	'lh.S_temporal_inf'	'lh.S_temporal_sup'	'lh.S_temporal_transverse'	'rh.Unknown'	'rh.G_and_S_frontomargin'	'rh.G_and_S_occipital_inf'	'rh.G_and_S_paracentral'	'rh.G_and_S_subcentral'	'rh.G_and_S_transv_frontopol'	'rh.G_and_S_cingul-Ant'	'rh.G_and_S_cingul-Mid-Ant'	'rh.G_and_S_cingul-Mid-Post'	'rh.G_cingul-Post-dorsal'	'rh.G_cingul-Post-ventral'	'rh.G_cuneus'	'rh.G_front_inf-Opercular'	'rh.G_front_inf-Orbital'	'rh.G_front_inf-Triangul'	'rh.G_front_middle'	'rh.G_front_sup'	'rh.G_Ins_lg_and_S_cent_ins'	'rh.G_insular_short'	'rh.G_occipital_middle'	'rh.G_occipital_sup'	'rh.G_oc-temp_lat-fusifor'	'rh.G_oc-temp_med-Lingual'	'rh.G_oc-temp_med-Parahip'	'rh.G_orbital'	'rh.G_pariet_inf-Angular'	'rh.G_pariet_inf-Supramar'	'rh.G_parietal_sup'	'rh.G_postcentral'	'rh.G_precentral'	'rh.G_precuneus'	'rh.G_rectus'	'rh.G_subcallosal'	'rh.G_temp_sup-G_T_transv'	'rh.G_temp_sup-Lateral'	'rh.G_temp_sup-Plan_polar'	'rh.G_temp_sup-Plan_tempo'	'rh.G_temporal_inf'	'rh.G_temporal_middle'	'rh.Lat_Fis-ant-Horizont'	'rh.Lat_Fis-ant-Vertical'	'rh.Lat_Fis-post'	'rh.Medial_wall'	'rh.Pole_occipital'	'rh.Pole_temporal'	'rh.S_calcarine'	'rh.S_central'	'rh.S_cingul-Marginalis'	'rh.S_circular_insula_ant'	'rh.S_circular_insula_inf'	'rh.S_circular_insula_sup'	'rh.S_collat_transv_ant'	'rh.S_collat_transv_post'	'rh.S_front_inf'	'rh.S_front_middle'	'rh.S_front_sup'	'rh.S_interm_prim-Jensen'	'rh.S_intrapariet_and_P_trans'	'rh.S_oc_middle_and_Lunatus'	'rh.S_oc_sup_and_transversal'	'rh.S_occipital_ant'	'rh.S_oc-temp_lat'	'rh.S_oc-temp_med_and_Lingual'	'rh.S_orbital_lateral'	'rh.S_orbital_med-olfact'	'rh.S_orbital-H_Shaped'	'rh.S_parieto_occipital'	'rh.S_pericallosal'	'rh.S_postcentral'	'rh.S_precentral-inf-part'	'rh.S_precentral-sup-part'	'rh.S_suborbital'	'rh.S_subparietal'	'rh.S_temporal_inf'	'rh.S_temporal_sup'	'rh.S_temporal_transverse'};
                        app.DropDown_RegionName.ItemsData = {'自定义' 'lh.Unknown'	'lh.G_and_S_frontomargin'	'lh.G_and_S_occipital_inf'	'lh.G_and_S_paracentral'	'lh.G_and_S_subcentral'	'lh.G_and_S_transv_frontopol'	'lh.G_and_S_cingul-Ant'	'lh.G_and_S_cingul-Mid-Ant'	'lh.G_and_S_cingul-Mid-Post'	'lh.G_cingul-Post-dorsal'	'lh.G_cingul-Post-ventral'	'lh.G_cuneus'	'lh.G_front_inf-Opercular'	'lh.G_front_inf-Orbital'	'lh.G_front_inf-Triangul'	'lh.G_front_middle'	'lh.G_front_sup'	'lh.G_Ins_lg_and_S_cent_ins'	'lh.G_insular_short'	'lh.G_occipital_middle'	'lh.G_occipital_sup'	'lh.G_oc-temp_lat-fusifor'	'lh.G_oc-temp_med-Lingual'	'lh.G_oc-temp_med-Parahip'	'lh.G_orbital'	'lh.G_pariet_inf-Angular'	'lh.G_pariet_inf-Supramar'	'lh.G_parietal_sup'	'lh.G_postcentral'	'lh.G_precentral'	'lh.G_precuneus'	'lh.G_rectus'	'lh.G_subcallosal'	'lh.G_temp_sup-G_T_transv'	'lh.G_temp_sup-Lateral'	'lh.G_temp_sup-Plan_polar'	'lh.G_temp_sup-Plan_tempo'	'lh.G_temporal_inf'	'lh.G_temporal_middle'	'lh.Lat_Fis-ant-Horizont'	'lh.Lat_Fis-ant-Vertical'	'lh.Lat_Fis-post'	'lh.Medial_wall'	'lh.Pole_occipital'	'lh.Pole_temporal'	'lh.S_calcarine'	'lh.S_central'	'lh.S_cingul-Marginalis'	'lh.S_circular_insula_ant'	'lh.S_circular_insula_inf'	'lh.S_circular_insula_sup'	'lh.S_collat_transv_ant'	'lh.S_collat_transv_post'	'lh.S_front_inf'	'lh.S_front_middle'	'lh.S_front_sup'	'lh.S_interm_prim-Jensen'	'lh.S_intrapariet_and_P_trans'	'lh.S_oc_middle_and_Lunatus'	'lh.S_oc_sup_and_transversal'	'lh.S_occipital_ant'	'lh.S_oc-temp_lat'	'lh.S_oc-temp_med_and_Lingual'	'lh.S_orbital_lateral'	'lh.S_orbital_med-olfact'	'lh.S_orbital-H_Shaped'	'lh.S_parieto_occipital'	'lh.S_pericallosal'	'lh.S_postcentral'	'lh.S_precentral-inf-part'	'lh.S_precentral-sup-part'	'lh.S_suborbital'	'lh.S_subparietal'	'lh.S_temporal_inf'	'lh.S_temporal_sup'	'lh.S_temporal_transverse'	'rh.Unknown'	'rh.G_and_S_frontomargin'	'rh.G_and_S_occipital_inf'	'rh.G_and_S_paracentral'	'rh.G_and_S_subcentral'	'rh.G_and_S_transv_frontopol'	'rh.G_and_S_cingul-Ant'	'rh.G_and_S_cingul-Mid-Ant'	'rh.G_and_S_cingul-Mid-Post'	'rh.G_cingul-Post-dorsal'	'rh.G_cingul-Post-ventral'	'rh.G_cuneus'	'rh.G_front_inf-Opercular'	'rh.G_front_inf-Orbital'	'rh.G_front_inf-Triangul'	'rh.G_front_middle'	'rh.G_front_sup'	'rh.G_Ins_lg_and_S_cent_ins'	'rh.G_insular_short'	'rh.G_occipital_middle'	'rh.G_occipital_sup'	'rh.G_oc-temp_lat-fusifor'	'rh.G_oc-temp_med-Lingual'	'rh.G_oc-temp_med-Parahip'	'rh.G_orbital'	'rh.G_pariet_inf-Angular'	'rh.G_pariet_inf-Supramar'	'rh.G_parietal_sup'	'rh.G_postcentral'	'rh.G_precentral'	'rh.G_precuneus'	'rh.G_rectus'	'rh.G_subcallosal'	'rh.G_temp_sup-G_T_transv'	'rh.G_temp_sup-Lateral'	'rh.G_temp_sup-Plan_polar'	'rh.G_temp_sup-Plan_tempo'	'rh.G_temporal_inf'	'rh.G_temporal_middle'	'rh.Lat_Fis-ant-Horizont'	'rh.Lat_Fis-ant-Vertical'	'rh.Lat_Fis-post'	'rh.Medial_wall'	'rh.Pole_occipital'	'rh.Pole_temporal'	'rh.S_calcarine'	'rh.S_central'	'rh.S_cingul-Marginalis'	'rh.S_circular_insula_ant'	'rh.S_circular_insula_inf'	'rh.S_circular_insula_sup'	'rh.S_collat_transv_ant'	'rh.S_collat_transv_post'	'rh.S_front_inf'	'rh.S_front_middle'	'rh.S_front_sup'	'rh.S_interm_prim-Jensen'	'rh.S_intrapariet_and_P_trans'	'rh.S_oc_middle_and_Lunatus'	'rh.S_oc_sup_and_transversal'	'rh.S_occipital_ant'	'rh.S_oc-temp_lat'	'rh.S_oc-temp_med_and_Lingual'	'rh.S_orbital_lateral'	'rh.S_orbital_med-olfact'	'rh.S_orbital-H_Shaped'	'rh.S_parieto_occipital'	'rh.S_pericallosal'	'rh.S_postcentral'	'rh.S_precentral-inf-part'	'rh.S_precentral-sup-part'	'rh.S_suborbital'	'rh.S_subparietal'	'rh.S_temporal_inf'	'rh.S_temporal_sup'	'rh.S_temporal_transverse'};
                        app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
                    case 'HCP_MMP1'
                        app.DropDown_RegionName.Items = {'自定义' 'lh.???'	'lh.V1'	'lh.MST'	'lh.V6'	'lh.V2'	'lh.V3'	'lh.V4'	'lh.V8'	'lh.4'	'lh.3b'	'lh.FEF'	'lh.PEF'	'lh.55b'	'lh.V3A'	'lh.RSC'	'lh.POS2'	'lh.V7'	'lh.IPS1'	'lh.FFC'	'lh.V3B'	'lh.LO1'	'lh.LO2'	'lh.PIT'	'lh.MT'	'lh.A1'	'lh.PSL'	'lh.SFL'	'lh.PCV'	'lh.STV'	'lh.7Pm'	'lh.7m'	'lh.POS1'	'lh.23d'	'lh.v23ab'	'lh.d23ab'	'lh.31pv'	'lh.5m'	'lh.5mv'	'lh.23c'	'lh.5L'	'lh.24dd'	'lh.24dv'	'lh.7AL'	'lh.SCEF'	'lh.6ma'	'lh.7Am'	'lh.7PL'	'lh.7PC'	'lh.LIPv'	'lh.VIP'	'lh.MIP'	'lh.1'	'lh.2'	'lh.3a'	'lh.6d'	'lh.6mp'	'lh.6v'	'lh.p24pr'	'lh.33pr'	'lh.a24pr'	'lh.p32pr'	'lh.a24'	'lh.d32'	'lh.8BM'	'lh.p32'	'lh.10r'	'lh.47m'	'lh.8Av'	'lh.8Ad'	'lh.9m'	'lh.8BL'	'lh.9p'	'lh.10d'	'lh.8C'	'lh.44'	'lh.45'	'lh.47l'	'lh.a47r'	'lh.6r'	'lh.IFJa'	'lh.IFJp'	'lh.IFSp'	'lh.IFSa'	'lh.p9-46v'	'lh.46'	'lh.a9-46v'	'lh.9-46d'	'lh.9a'	'lh.10v'	'lh.a10p'	'lh.10pp'	'lh.11l'	'lh.13l'	'lh.OFC'	'lh.47s'	'lh.LIPd'	'lh.6a'	'lh.i6-8'	'lh.s6-8'	'lh.43'	'lh.OP4'	'lh.OP1'	'lh.OP2-3'	'lh.52'	'lh.RI'	'lh.PFcm'	'lh.PoI2'	'lh.TA2'	'lh.FOP4'	'lh.MI'	'lh.Pir'	'lh.AVI'	'lh.AAIC'	'lh.FOP1'	'lh.FOP3'	'lh.FOP2'	'lh.PFt'	'lh.AIP'	'lh.EC'	'lh.PreS'	'lh.H'	'lh.ProS'	'lh.PeEc'	'lh.STGa'	'lh.PBelt'	'lh.A5'	'lh.PHA1'	'lh.PHA3'	'lh.STSda'	'lh.STSdp'	'lh.STSvp'	'lh.TGd'	'lh.TE1a'	'lh.TE1p'	'lh.TE2a'	'lh.TF'	'lh.TE2p'	'lh.PHT'	'lh.PH'	'lh.TPOJ1'	'lh.TPOJ2'	'lh.TPOJ3'	'lh.DVT'	'lh.PGp'	'lh.IP2'	'lh.IP1'	'lh.IP0'	'lh.PFop'	'lh.PF'	'lh.PFm'	'lh.PGi'	'lh.PGs'	'lh.V6A'	'lh.VMV1'	'lh.VMV3'	'lh.PHA2'	'lh.V4t'	'lh.FST'	'lh.V3CD'	'lh.LO3'	'lh.VMV2'	'lh.31pd'	'lh.31a'	'lh.VVC'	'lh.25'	'lh.s32'	'lh.pOFC'	'lh.PoI1'	'lh.Ig'	'lh.FOP5'	'lh.p10p'	'lh.p47r'	'lh.TGv'	'lh.MBelt'	'lh.LBelt'	'lh.A4'	'lh.STSva'	'lh.TE1m'	'lh.PI'	'lh.a32pr'	'lh.p24'	'rh.???'	'rh.V1'	'rh.MST'	'rh.V6'	'rh.V2'	'rh.V3'	'rh.V4'	'rh.V8'	'rh.4'	'rh.3b'	'rh.FEF'	'rh.PEF'	'rh.55b'	'rh.V3A'	'rh.RSC'	'rh.POS2'	'rh.V7'	'rh.IPS1'	'rh.FFC'	'rh.V3B'	'rh.LO1'	'rh.LO2'	'rh.PIT'	'rh.MT'	'rh.A1'	'rh.PSL'	'rh.SFL'	'rh.PCV'	'rh.STV'	'rh.7Pm'	'rh.7m'	'rh.POS1'	'rh.23d'	'rh.v23ab'	'rh.d23ab'	'rh.31pv'	'rh.5m'	'rh.5mv'	'rh.23c'	'rh.5L'	'rh.24dd'	'rh.24dv'	'rh.7AL'	'rh.SCEF'	'rh.6ma'	'rh.7Am'	'rh.7PL'	'rh.7PC'	'rh.LIPv'	'rh.VIP'	'rh.MIP'	'rh.1'	'rh.2'	'rh.3a'	'rh.6d'	'rh.6mp'	'rh.6v'	'rh.p24pr'	'rh.33pr'	'rh.a24pr'	'rh.p32pr'	'rh.a24'	'rh.d32'	'rh.8BM'	'rh.p32'	'rh.10r'	'rh.47m'	'rh.8Av'	'rh.8Ad'	'rh.9m'	'rh.8BL'	'rh.9p'	'rh.10d'	'rh.8C'	'rh.44'	'rh.45'	'rh.47l'	'rh.a47r'	'rh.6r'	'rh.IFJa'	'rh.IFJp'	'rh.IFSp'	'rh.IFSa'	'rh.p9-46v'	'rh.46'	'rh.a9-46v'	'rh.9-46d'	'rh.9a'	'rh.10v'	'rh.a10p'	'rh.10pp'	'rh.11l'	'rh.13l'	'rh.OFC'	'rh.47s'	'rh.LIPd'	'rh.6a'	'rh.i6-8'	'rh.s6-8'	'rh.43'	'rh.OP4'	'rh.OP1'	'rh.OP2-3'	'rh.52'	'rh.RI'	'rh.PFcm'	'rh.PoI2'	'rh.TA2'	'rh.FOP4'	'rh.MI'	'rh.Pir'	'rh.AVI'	'rh.AAIC'	'rh.FOP1'	'rh.FOP3'	'rh.FOP2'	'rh.PFt'	'rh.AIP'	'rh.EC'	'rh.PreS'	'rh.H'	'rh.ProS'	'rh.PeEc'	'rh.STGa'	'rh.PBelt'	'rh.A5'	'rh.PHA1'	'rh.PHA3'	'rh.STSda'	'rh.STSdp'	'rh.STSvp'	'rh.TGd'	'rh.TE1a'	'rh.TE1p'	'rh.TE2a'	'rh.TF'	'rh.TE2p'	'rh.PHT'	'rh.PH'	'rh.TPOJ1'	'rh.TPOJ2'	'rh.TPOJ3'	'rh.DVT'	'rh.PGp'	'rh.IP2'	'rh.IP1'	'rh.IP0'	'rh.PFop'	'rh.PF'	'rh.PFm'	'rh.PGi'	'rh.PGs'	'rh.V6A'	'rh.VMV1'	'rh.VMV3'	'rh.PHA2'	'rh.V4t'	'rh.FST'	'rh.V3CD'	'rh.LO3'	'rh.VMV2'	'rh.31pd'	'rh.31a'	'rh.VVC'	'rh.25'	'rh.s32'	'rh.pOFC'	'rh.PoI1'	'rh.Ig'	'rh.FOP5'	'rh.p10p'	'rh.p47r'	'rh.TGv'	'rh.MBelt'	'rh.LBelt'	'rh.A4'	'rh.STSva'	'rh.TE1m'	'rh.PI'	'rh.a32pr'	'rh.p24'};
                        app.DropDown_RegionName.ItemsData = {'自定义' 'lh.???'	'lh.V1'	'lh.MST'	'lh.V6'	'lh.V2'	'lh.V3'	'lh.V4'	'lh.V8'	'lh.4'	'lh.3b'	'lh.FEF'	'lh.PEF'	'lh.55b'	'lh.V3A'	'lh.RSC'	'lh.POS2'	'lh.V7'	'lh.IPS1'	'lh.FFC'	'lh.V3B'	'lh.LO1'	'lh.LO2'	'lh.PIT'	'lh.MT'	'lh.A1'	'lh.PSL'	'lh.SFL'	'lh.PCV'	'lh.STV'	'lh.7Pm'	'lh.7m'	'lh.POS1'	'lh.23d'	'lh.v23ab'	'lh.d23ab'	'lh.31pv'	'lh.5m'	'lh.5mv'	'lh.23c'	'lh.5L'	'lh.24dd'	'lh.24dv'	'lh.7AL'	'lh.SCEF'	'lh.6ma'	'lh.7Am'	'lh.7PL'	'lh.7PC'	'lh.LIPv'	'lh.VIP'	'lh.MIP'	'lh.1'	'lh.2'	'lh.3a'	'lh.6d'	'lh.6mp'	'lh.6v'	'lh.p24pr'	'lh.33pr'	'lh.a24pr'	'lh.p32pr'	'lh.a24'	'lh.d32'	'lh.8BM'	'lh.p32'	'lh.10r'	'lh.47m'	'lh.8Av'	'lh.8Ad'	'lh.9m'	'lh.8BL'	'lh.9p'	'lh.10d'	'lh.8C'	'lh.44'	'lh.45'	'lh.47l'	'lh.a47r'	'lh.6r'	'lh.IFJa'	'lh.IFJp'	'lh.IFSp'	'lh.IFSa'	'lh.p9-46v'	'lh.46'	'lh.a9-46v'	'lh.9-46d'	'lh.9a'	'lh.10v'	'lh.a10p'	'lh.10pp'	'lh.11l'	'lh.13l'	'lh.OFC'	'lh.47s'	'lh.LIPd'	'lh.6a'	'lh.i6-8'	'lh.s6-8'	'lh.43'	'lh.OP4'	'lh.OP1'	'lh.OP2-3'	'lh.52'	'lh.RI'	'lh.PFcm'	'lh.PoI2'	'lh.TA2'	'lh.FOP4'	'lh.MI'	'lh.Pir'	'lh.AVI'	'lh.AAIC'	'lh.FOP1'	'lh.FOP3'	'lh.FOP2'	'lh.PFt'	'lh.AIP'	'lh.EC'	'lh.PreS'	'lh.H'	'lh.ProS'	'lh.PeEc'	'lh.STGa'	'lh.PBelt'	'lh.A5'	'lh.PHA1'	'lh.PHA3'	'lh.STSda'	'lh.STSdp'	'lh.STSvp'	'lh.TGd'	'lh.TE1a'	'lh.TE1p'	'lh.TE2a'	'lh.TF'	'lh.TE2p'	'lh.PHT'	'lh.PH'	'lh.TPOJ1'	'lh.TPOJ2'	'lh.TPOJ3'	'lh.DVT'	'lh.PGp'	'lh.IP2'	'lh.IP1'	'lh.IP0'	'lh.PFop'	'lh.PF'	'lh.PFm'	'lh.PGi'	'lh.PGs'	'lh.V6A'	'lh.VMV1'	'lh.VMV3'	'lh.PHA2'	'lh.V4t'	'lh.FST'	'lh.V3CD'	'lh.LO3'	'lh.VMV2'	'lh.31pd'	'lh.31a'	'lh.VVC'	'lh.25'	'lh.s32'	'lh.pOFC'	'lh.PoI1'	'lh.Ig'	'lh.FOP5'	'lh.p10p'	'lh.p47r'	'lh.TGv'	'lh.MBelt'	'lh.LBelt'	'lh.A4'	'lh.STSva'	'lh.TE1m'	'lh.PI'	'lh.a32pr'	'lh.p24'	'rh.???'	'rh.V1'	'rh.MST'	'rh.V6'	'rh.V2'	'rh.V3'	'rh.V4'	'rh.V8'	'rh.4'	'rh.3b'	'rh.FEF'	'rh.PEF'	'rh.55b'	'rh.V3A'	'rh.RSC'	'rh.POS2'	'rh.V7'	'rh.IPS1'	'rh.FFC'	'rh.V3B'	'rh.LO1'	'rh.LO2'	'rh.PIT'	'rh.MT'	'rh.A1'	'rh.PSL'	'rh.SFL'	'rh.PCV'	'rh.STV'	'rh.7Pm'	'rh.7m'	'rh.POS1'	'rh.23d'	'rh.v23ab'	'rh.d23ab'	'rh.31pv'	'rh.5m'	'rh.5mv'	'rh.23c'	'rh.5L'	'rh.24dd'	'rh.24dv'	'rh.7AL'	'rh.SCEF'	'rh.6ma'	'rh.7Am'	'rh.7PL'	'rh.7PC'	'rh.LIPv'	'rh.VIP'	'rh.MIP'	'rh.1'	'rh.2'	'rh.3a'	'rh.6d'	'rh.6mp'	'rh.6v'	'rh.p24pr'	'rh.33pr'	'rh.a24pr'	'rh.p32pr'	'rh.a24'	'rh.d32'	'rh.8BM'	'rh.p32'	'rh.10r'	'rh.47m'	'rh.8Av'	'rh.8Ad'	'rh.9m'	'rh.8BL'	'rh.9p'	'rh.10d'	'rh.8C'	'rh.44'	'rh.45'	'rh.47l'	'rh.a47r'	'rh.6r'	'rh.IFJa'	'rh.IFJp'	'rh.IFSp'	'rh.IFSa'	'rh.p9-46v'	'rh.46'	'rh.a9-46v'	'rh.9-46d'	'rh.9a'	'rh.10v'	'rh.a10p'	'rh.10pp'	'rh.11l'	'rh.13l'	'rh.OFC'	'rh.47s'	'rh.LIPd'	'rh.6a'	'rh.i6-8'	'rh.s6-8'	'rh.43'	'rh.OP4'	'rh.OP1'	'rh.OP2-3'	'rh.52'	'rh.RI'	'rh.PFcm'	'rh.PoI2'	'rh.TA2'	'rh.FOP4'	'rh.MI'	'rh.Pir'	'rh.AVI'	'rh.AAIC'	'rh.FOP1'	'rh.FOP3'	'rh.FOP2'	'rh.PFt'	'rh.AIP'	'rh.EC'	'rh.PreS'	'rh.H'	'rh.ProS'	'rh.PeEc'	'rh.STGa'	'rh.PBelt'	'rh.A5'	'rh.PHA1'	'rh.PHA3'	'rh.STSda'	'rh.STSdp'	'rh.STSvp'	'rh.TGd'	'rh.TE1a'	'rh.TE1p'	'rh.TE2a'	'rh.TF'	'rh.TE2p'	'rh.PHT'	'rh.PH'	'rh.TPOJ1'	'rh.TPOJ2'	'rh.TPOJ3'	'rh.DVT'	'rh.PGp'	'rh.IP2'	'rh.IP1'	'rh.IP0'	'rh.PFop'	'rh.PF'	'rh.PFm'	'rh.PGi'	'rh.PGs'	'rh.V6A'	'rh.VMV1'	'rh.VMV3'	'rh.PHA2'	'rh.V4t'	'rh.FST'	'rh.V3CD'	'rh.LO3'	'rh.VMV2'	'rh.31pd'	'rh.31a'	'rh.VVC'	'rh.25'	'rh.s32'	'rh.pOFC'	'rh.PoI1'	'rh.Ig'	'rh.FOP5'	'rh.p10p'	'rh.p47r'	'rh.TGv'	'rh.MBelt'	'rh.LBelt'	'rh.A4'	'rh.STSva'	'rh.TE1m'	'rh.PI'	'rh.a32pr'	'rh.p24'};
                        app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
                    case 'AAL'
                        app.DropDown_RegionName.Items = {'自定义' 'Precentral_L'	'Precentral_R'	'Frontal_Sup_2_L'	'Frontal_Sup_2_R'	'Frontal_Mid_2_L'	'Frontal_Mid_2_R'	'Frontal_Inf_Oper_L'	'Frontal_Inf_Oper_R'	'Frontal_Inf_Tri_L'	'Frontal_Inf_Tri_R'	'Frontal_Inf_Orb_2_L'	'Frontal_Inf_Orb_2_R'	'Rolandic_Oper_L'	'Rolandic_Oper_R'	'Supp_Motor_Area_L'	'Supp_Motor_Area_R'	'Olfactory_L'	'Olfactory_R'	'Frontal_Sup_Medial_L'	'Frontal_Sup_Medial_R'	'Frontal_Med_Orb_L'	'Frontal_Med_Orb_R'	'Rectus_L'	'Rectus_R'	'OFCmed_L'	'OFCmed_R'	'OFCant_L'	'OFCant_R'	'OFCpost_L'	'OFCpost_R'	'OFClat_L'	'OFClat_R'	'Insula_L'	'Insula_R'	'Cingulate_Mid_L'	'Cingulate_Mid_R'	'Cingulate_Post_L'	'Cingulate_Post_R'	'Hippocampus_L'	'Hippocampus_R'	'ParaHippocampal_L'	'ParaHippocampal_R'	'Amygdala_L'	'Amygdala_R'	'Calcarine_L'	'Calcarine_R'	'Cuneus_L'	'Cuneus_R'	'Lingual_L'	'Lingual_R'	'Occipital_Sup_L'	'Occipital_Sup_R'	'Occipital_Mid_L'	'Occipital_Mid_R'	'Occipital_Inf_L'	'Occipital_Inf_R'	'Fusiform_L'	'Fusiform_R'	'Postcentral_L'	'Postcentral_R'	'Parietal_Sup_L'	'Parietal_Sup_R'	'Parietal_Inf_L'	'Parietal_Inf_R'	'SupraMarginal_L'	'SupraMarginal_R'	'Angular_L'	'Angular_R'	'Precuneus_L'	'Precuneus_R'	'Paracentral_Lobule_L'	'Paracentral_Lobule_R'	'Caudate_L'	'Caudate_R'	'Putamen_L'	'Putamen_R'	'Pallidum_L'	'Pallidum_R'	'Heschl_L'	'Heschl_R'	'Temporal_Sup_L'	'Temporal_Sup_R'	'Temporal_Pole_Sup_L'	'Temporal_Pole_Sup_R'	'Temporal_Mid_L'	'Temporal_Mid_R'	'Temporal_Pole_Mid_L'	'Temporal_Pole_Mid_R'	'Temporal_Inf_L'	'Temporal_Inf_R'	'Cerebellum_Crus1_L'	'Cerebellum_Crus1_R'	'Cerebellum_Crus2_L'	'Cerebellum_Crus2_R'	'Cerebellum_3_L'	'Cerebellum_3_R'	'Cerebellum_4_5_L'	'Cerebellum_4_5_R'	'Cerebellum_6_L'	'Cerebellum_6_R'	'Cerebellum_7b_L'	'Cerebellum_7b_R'	'Cerebellum_8_L'	'Cerebellum_8_R'	'Cerebellum_9_L'	'Cerebellum_9_R'	'Cerebellum_10_L'	'Cerebellum_10_R'	'Vermis_1_2'	'Vermis_3'	'Vermis_4_5'	'Vermis_6'	'Vermis_7'	'Vermis_8'	'Vermis_9'	'Vermis_10'	'Thal_AV_L'	'Thal_AV_R'	'Thal_LP_L'	'Thal_LP_R'	'Thal_VA_L'	'Thal_VA_R'	'Thal_VL_L'	'Thal_VL_R'	'Thal_VPL_L'	'Thal_VPL_R'	'Thal_IL_L'	'Thal_IL_R'	'Thal_Re_L'	'Thal_Re_R'	'Thal_MDm_L'	'Thal_MDm_R'	'Thal_MDl_L'	'Thal_MDl_R'	'Thal_LGN_L'	'Thal_LGN_R'	'Thal_MGN_L'	'Thal_MGN_R'	'Thal_PuI_L'	'Thal_PuI_R'	'Thal_PuM_L'	'Thal_PuM_R'	'Thal_PuA_L'	'Thal_PuA_R'	'Thal_PuL_L'	'Thal_PuL_R'	'ACC_sub_L'	'ACC_sub_R'	'ACC_pre_L'	'ACC_pre_R'	'ACC_sup_L'	'ACC_sup_R'	'N_Acc_L'	'N_Acc_R'	'VTA_L'	'VTA_R'	'SN_pc_L'	'SN_pc_R'	'SN_pr_L'	'SN_pr_R'	'Red_N_L'	'Red_N_R'	'LC_L'	'LC_R'	'Raphe_D'	'Raphe_M'};
                        app.DropDown_RegionName.ItemsData = {'自定义' 'Precentral_L'	'Precentral_R'	'Frontal_Sup_2_L'	'Frontal_Sup_2_R'	'Frontal_Mid_2_L'	'Frontal_Mid_2_R'	'Frontal_Inf_Oper_L'	'Frontal_Inf_Oper_R'	'Frontal_Inf_Tri_L'	'Frontal_Inf_Tri_R'	'Frontal_Inf_Orb_2_L'	'Frontal_Inf_Orb_2_R'	'Rolandic_Oper_L'	'Rolandic_Oper_R'	'Supp_Motor_Area_L'	'Supp_Motor_Area_R'	'Olfactory_L'	'Olfactory_R'	'Frontal_Sup_Medial_L'	'Frontal_Sup_Medial_R'	'Frontal_Med_Orb_L'	'Frontal_Med_Orb_R'	'Rectus_L'	'Rectus_R'	'OFCmed_L'	'OFCmed_R'	'OFCant_L'	'OFCant_R'	'OFCpost_L'	'OFCpost_R'	'OFClat_L'	'OFClat_R'	'Insula_L'	'Insula_R'	'Cingulate_Mid_L'	'Cingulate_Mid_R'	'Cingulate_Post_L'	'Cingulate_Post_R'	'Hippocampus_L'	'Hippocampus_R'	'ParaHippocampal_L'	'ParaHippocampal_R'	'Amygdala_L'	'Amygdala_R'	'Calcarine_L'	'Calcarine_R'	'Cuneus_L'	'Cuneus_R'	'Lingual_L'	'Lingual_R'	'Occipital_Sup_L'	'Occipital_Sup_R'	'Occipital_Mid_L'	'Occipital_Mid_R'	'Occipital_Inf_L'	'Occipital_Inf_R'	'Fusiform_L'	'Fusiform_R'	'Postcentral_L'	'Postcentral_R'	'Parietal_Sup_L'	'Parietal_Sup_R'	'Parietal_Inf_L'	'Parietal_Inf_R'	'SupraMarginal_L'	'SupraMarginal_R'	'Angular_L'	'Angular_R'	'Precuneus_L'	'Precuneus_R'	'Paracentral_Lobule_L'	'Paracentral_Lobule_R'	'Caudate_L'	'Caudate_R'	'Putamen_L'	'Putamen_R'	'Pallidum_L'	'Pallidum_R'	'Heschl_L'	'Heschl_R'	'Temporal_Sup_L'	'Temporal_Sup_R'	'Temporal_Pole_Sup_L'	'Temporal_Pole_Sup_R'	'Temporal_Mid_L'	'Temporal_Mid_R'	'Temporal_Pole_Mid_L'	'Temporal_Pole_Mid_R'	'Temporal_Inf_L'	'Temporal_Inf_R'	'Cerebellum_Crus1_L'	'Cerebellum_Crus1_R'	'Cerebellum_Crus2_L'	'Cerebellum_Crus2_R'	'Cerebellum_3_L'	'Cerebellum_3_R'	'Cerebellum_4_5_L'	'Cerebellum_4_5_R'	'Cerebellum_6_L'	'Cerebellum_6_R'	'Cerebellum_7b_L'	'Cerebellum_7b_R'	'Cerebellum_8_L'	'Cerebellum_8_R'	'Cerebellum_9_L'	'Cerebellum_9_R'	'Cerebellum_10_L'	'Cerebellum_10_R'	'Vermis_1_2'	'Vermis_3'	'Vermis_4_5'	'Vermis_6'	'Vermis_7'	'Vermis_8'	'Vermis_9'	'Vermis_10'	'Thal_AV_L'	'Thal_AV_R'	'Thal_LP_L'	'Thal_LP_R'	'Thal_VA_L'	'Thal_VA_R'	'Thal_VL_L'	'Thal_VL_R'	'Thal_VPL_L'	'Thal_VPL_R'	'Thal_IL_L'	'Thal_IL_R'	'Thal_Re_L'	'Thal_Re_R'	'Thal_MDm_L'	'Thal_MDm_R'	'Thal_MDl_L'	'Thal_MDl_R'	'Thal_LGN_L'	'Thal_LGN_R'	'Thal_MGN_L'	'Thal_MGN_R'	'Thal_PuI_L'	'Thal_PuI_R'	'Thal_PuM_L'	'Thal_PuM_R'	'Thal_PuA_L'	'Thal_PuA_R'	'Thal_PuL_L'	'Thal_PuL_R'	'ACC_sub_L'	'ACC_sub_R'	'ACC_pre_L'	'ACC_pre_R'	'ACC_sup_L'	'ACC_sup_R'	'N_Acc_L'	'N_Acc_R'	'VTA_L'	'VTA_R'	'SN_pc_L'	'SN_pc_R'	'SN_pr_L'	'SN_pr_R'	'Red_N_L'	'Red_N_R'	'LC_L'	'LC_R'	'Raphe_D'	'Raphe_M'};
                        app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
                end
            end
            
        end

        % Value changed function: DropDown_RegionName
        function DropDown_RegionNameValueChanged(app, event)
            value = app.DropDown_RegionName.Value;
            if ~isempty(value)
                app.regionname = app.DropDown_RegionName.Value;
                % Get the center MNI coord of the ROI
                switch app.eletype
                    case 'tri'
                        surf = mesh_load_gmsh4(fullfile(app.inputpath,[app.subMark '.msh']));
                        [labels, snames] = subject_atlas(surf, fullfile(app.inputpath, ['m2m_' app.subMark]), app.atlas);
                        roi_idx = find(strcmpi(snames, app.regionname));
                        node_idx = labels.node_data{end}.data==roi_idx;
                        node_gc = double(mean(surf.nodes(node_idx,:)));
                        disp(node_gc);
                        app.coordMNI = subject2mni_coords(node_gc,fullfile(app.inputpath, ['m2m_' app.subMark]));
                        app.XEditField.Value = app.coordMNI(1);
                        app.YEditField.Value = app.coordMNI(2);
                        app.ZEditField.Value = app.coordMNI(3);
                end
            end
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            if value ~= app.coordMNI(1)
                app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
                app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
            end
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            if value ~= app.coordMNI(2)
                app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
                app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
            end
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            if value ~= app.coordMNI(3)
                app.DropDown_Atlas.Value = app.DropDown_Atlas.ItemsData{1};
                app.DropDown_RegionName.Value = app.DropDown_RegionName.ItemsData{1};
            end
        end

        % Value changed function: EditField_r
        function EditField_rValueChanged(app, event)
            app.r = app.EditField_r.Value;
        end

        % Value changed function: EditField_label
        function EditField_labelValueChanged(app, event)
            app.label = app.EditField_label.Value;
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            updataROIPara(app.CallingApp,app.regionname,app.coordMNI,app.r,app.label);
            delete(app);
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_DialogROI = uifigure('Visible', 'off');
            app.UI_DialogROI.Name = 'MATLAB App';
            app.Grid_DialogROI = uigridlayout(app.UI_DialogROI);
            app.Panel_ROI = uipanel(app.Grid_DialogROI);
            app.Panel_ROI.Title = '目标脑区';
            app.Grid_ROI = uigridlayout(app.Panel_ROI);
            app.Label = uilabel(app.Grid_ROI);
            app.Label.Text = '地图集';
            app.DropDown_Atlas = uidropdown(app.Grid_ROI);
            app.DropDown_Atlas.Items = {};
            app.DropDown_Atlas.ValueChangedFcn = createCallbackFcn(app, @DropDown_AtlasValueChanged, true);
            app.DropDown_Atlas.Value = {};
            app.DropDown_2Label = uilabel(app.Grid_ROI);
            app.DropDown_2Label.Text = '脑区名称';
            app.DropDown_RegionName = uidropdown(app.Grid_ROI);
            app.DropDown_RegionName.Items = {};
            app.DropDown_RegionName.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegionNameValueChanged, true);
            app.DropDown_RegionName.Value = {};
            app.Label_2 = uilabel(app.Grid_ROI);
            app.Label_2.Text = '脑区半径';
            app.EditField_r = uieditfield(app.Grid_ROI, 'numeric');
            app.EditField_r.ValueChangedFcn = createCallbackFcn(app, @EditField_rValueChanged, true);
            app.EditField_r.Value = 10;
            app.Button_OK = uibutton(app.Grid_ROI, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Text = '确认';
            app.Button_Cancel = uibutton(app.Grid_ROI, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            app.XEditField = uieditfield(app.Grid_ROI, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditField = uieditfield(app.Grid_ROI, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditField = uieditfield(app.Grid_ROI, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.XLabel = uilabel(app.Grid_ROI);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Grid_ROI);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.Grid_ROI);
            app.ZLabel.Text = 'Z';
            app.EditField_label = uieditfield(app.Grid_ROI, 'text');
            app.EditField_label.ValueChangedFcn = createCallbackFcn(app, @EditField_labelValueChanged, true);
            app.Label_label = uilabel(app.Grid_ROI);
            app.Label_label.Text = '标签';
            app.ContextMenu = uicontextmenu(app.UI_DialogROI);
            % Assign app.ContextMenu
            app.Label_2.ContextMenu = app.ContextMenu;
            app.mmMenu_r = uimenu(app.ContextMenu);
            app.mmMenu_r.Text = '单位：mm';
            app.ContextMenu2 = uicontextmenu(app.UI_DialogROI);
            % Assign app.ContextMenu2
            app.EditField_label.ContextMenu = app.ContextMenu2;
            app.Menu_label = uimenu(app.ContextMenu2);
            app.Menu_label.Text = '可以给选定的脑区名称加后缀；也可以在自定义脑区的时候为脑区进行命名';
            app.ContextMenu3 = uicontextmenu(app.UI_DialogROI);
            % Assign app.ContextMenu3
            app.XLabel.ContextMenu = app.ContextMenu3;
            app.MNIMenu_X = uimenu(app.ContextMenu3);
            app.MNIMenu_X.Text = '脑区中心MNI坐标';
            app.ContextMenu4 = uicontextmenu(app.UI_DialogROI);
            % Assign app.ContextMenu4
            app.YLabel.ContextMenu = app.ContextMenu4;
            app.MNIMenu_Y = uimenu(app.ContextMenu4);
            app.MNIMenu_Y.Text = '脑区中心MNI坐标';
            app.ContextMenu5 = uicontextmenu(app.UI_DialogROI);
            % Assign app.ContextMenu5
            app.ZLabel.ContextMenu = app.ContextMenu5;
            app.MNIMenu_Z = uimenu(app.ContextMenu5);
            app.MNIMenu_Z.Text = '脑区中心MNI坐标';
            % Show the figure after all components are created
            app.UI_DialogROI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialog_ROI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_DialogROI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_DialogROI)
        end
    end
end