classdef OpenTIPS_eng < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI_OpenTIPS                  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Panel_Settings               matlab.ui.container.Panel
        Grid_Settings                matlab.ui.container.GridLayout
        ROIPanel                     matlab.ui.container.Panel
        Button_ROIDisplay            matlab.ui.control.Button
        Button_ROIMinus              matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Button_ROIAdd                matlab.ui.control.Button
        PenaltyPanel                 matlab.ui.container.Panel
        Button_PenaltyDisplay        matlab.ui.control.Button
        Button_PenaltyMinus          matlab.ui.control.Button
        Button_PenaltyAdd            matlab.ui.control.Button
        UITable_Penalty              matlab.ui.control.Table
        OptimalcoefficientsPanel     matlab.ui.container.Panel
        coefficient                  matlab.ui.container.GridLayout
        electrodenumDropDown         matlab.ui.control.DropDown
        electrodenumDropDownLabel    matlab.ui.control.Label
        electrodecandidateEditField  matlab.ui.control.NumericEditField
        electrodecandidateEditFieldLabel  matlab.ui.control.Label
        CalculationButton            matlab.ui.control.Button
        PenaltypEditField            matlab.ui.control.NumericEditField
        PenaltypEditFieldLabel       matlab.ui.control.Label
        OtheralphaEditField          matlab.ui.control.NumericEditField
        OtheralphaEditFieldLabel     matlab.ui.control.Label
        ROIalphaEditField            matlab.ui.control.NumericEditField
        ROIalphaEditFieldLabel       matlab.ui.control.Label
        ROIthresholdEditField        matlab.ui.control.NumericEditField
        ROIthresholdEditFieldLabel   matlab.ui.control.Label
        UITable_ROI                  matlab.ui.control.Table
        orientationPanel             matlab.ui.container.Panel
        graymatterCheckBox           matlab.ui.control.CheckBox
        whitematterCheckBox          matlab.ui.control.CheckBox
        Panel_dataRoot               matlab.ui.container.Panel
        Grid_dataRoot                matlab.ui.container.GridLayout
        Button_dataRoot              matlab.ui.control.Button
        EditField_dataRoot           matlab.ui.control.EditField
        Panel_subMark                matlab.ui.container.Panel
        DropDown_subMark             matlab.ui.control.DropDown
        Panel_simMark                matlab.ui.container.Panel
        EditField_simMark            matlab.ui.control.EditField
        ElementtypeButtonGroup       matlab.ui.container.ButtonGroup
        triangleButton               matlab.ui.control.RadioButton
        tetraheronButton             matlab.ui.control.RadioButton
        Panel_Plots                  matlab.ui.container.Panel
        Grid_Plots                   matlab.ui.container.GridLayout
        Panel_dataRoot_2             matlab.ui.container.Panel
        Grid_dataRoot_2              matlab.ui.container.GridLayout
        Button_dataRoot_2            matlab.ui.control.Button
        EditField_dataRoot_2         matlab.ui.control.EditField
        Image_Logo                   matlab.ui.control.Image
        UIAxes_figure                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        DialogPenaltyApp; % The app window for Penalty parameters
        ROIPara = {}; % Parameter for ROI(s)
        PenaltyPara = {}; % Parameter for Penalty area(s)
        MergedPara = {}; % Merged parameters for ROI and Penalty area(s)
        eletype = 'tri';
        gmsTR;
        ROI_TR;
        Penalty_TR;
        ROIColor = [139,0,0];
        PenaltyColor = [0,0,139];
        ROIview;
        hobj;
        varTypes = ["string","double","double"];
        varNames = ["Name","CoordMNI","Radius"];
    end

    properties (Access = public)
        dataRoot;
        subMark;
        simMark;
        InputPath; % The folder of data input
        OutputPath; % The folder of data Output
        m2mPath;
        cfg;
        row_ROI_table_selected;
        row_Penalty_table_selected;
    end

    methods (Access = public)
        function updateTarget(app,regionName,coordMNI,r,mark)
            table_tmp = {regionName,coordMNI,r};
            table_tmp_str = {regionName,num2str(coordMNI),num2str(r)};
            disp(mark);
            switch mark
                case 'ROI'
                    app.cfg.ROI.num = app.cfg.ROI.num + 1;
                    app.cfg.ROI.table = [app.cfg.ROI.table; table_tmp];
                    app.UITable_ROI.Data = [app.UITable_ROI.Data; table_tmp_str];
                    disp(app.cfg.ROI.table);
                case 'Penalty'
                    app.cfg.Penalty.num = app.cfg.Penalty.num + 1;
                    app.cfg.Penalty.table = [app.cfg.Penalty.table; table_tmp];
                    app.UITable_Penalty.Data = [app.UITable_Penalty.Data; table_tmp_str];
                    disp(app.cfg.Penalty.table);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                %% dataRoot
                app.dataRoot = evalin('base','dataRoot');
                app.EditField_dataRoot.Value = app.dataRoot;
                %% subMark List
                d = dir(app.dataRoot);
                f = {d([d.isdir]).name};
                subList = f(3:end);
                app.DropDown_subMark.Items = subList;
                %% subMark
                subMark = evalin('base','subMark');
                if ismember(subMark,subList)
                    app.subMark = subMark;
                    app.DropDown_subMark.Value = app.subMark;
                else
                    if ~isempty(subList)
                        app.subMark = subList{1};
                        app.DropDown_subMark.Value = app.subMark;
                    else
                        error('No available subMark under dataRoot!');
                    end
                end
                %%
                app.InputPath = fullfile(app.dataRoot,app.subMark);
                app.simMark = evalin('base','simMark');
                app.EditField_simMark.Value = app.simMark;
                app.OutputPath = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
                app.m2mPath = fullfile(app.dataRoot,app.subMark, ['m2m_' app.subMark]);
                %% figure
                LFPath = fullfile(app.dataRoot,app.subMark,'leadfield');
                LFFile = fullfile(LFPath,[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
                if exist(LFFile,'file')~=2
                    disp('Leadfield file for gray matter surface not existed!');
                else
                    m0 = mesh_load_hdf5(LFFile);
                    m1 = m0(2).mesh;
                    gmS = mesh_extract_regions(m1, 'region_idx', [1 2]); % gray matter surface in both hemisphere
                    app.gmsTR = triangulation(double(gmS.triangles),gmS.nodes);
                    app.hobj = trimesh(app.gmsTR.ConnectivityList,app.gmsTR.Points(:,1),app.gmsTR.Points(:,2),app.gmsTR.Points(:,3),'EdgeColor','none',...
                        'FaceColor', [130/255 130/255 130/255],'FaceAlpha',0.05,'Parent',app.UIAxes_figure);
                    title(app.UIAxes_figure,app.subMark,'FontWeight','normal');
                    lighting(app.UIAxes_figure,'gouraud');
                    rotate3d(app.UIAxes_figure,'on');
                    set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
                    axis(app.UIAxes_figure,'off');
                    material(app.hobj,'dull');
                end
            catch

            end
            %%
            selectedButton = app.ElementtypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'triangle'
                    app.cfg.type = 'tri';
                case 'tetraheron'
                    app.cfg.type = 'tet';
            end
            %% cfg
            app.cfg.ROI.num = 0;
            app.cfg.Penalty.num = 0;
            app.cfg.ROI.table = table('Size',[0,3],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.cfg.Penalty.table = table('Size',[0,3],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.cfg.ntGM = false;
            app.cfg.ntWM = false;
            app.cfg.elecNum = 4;
            app.cfg.thres = 0.2;
            app.cfg.Penalty.coef = 2;
            app.cfg.elecCandNum = 20;
            app.cfg.method_ROI = 0;
            app.cfg.method_Other = 2;
        end

        % Button pushed function: Button_dataRoot
        function Button_dataRootPushed(app, event)
            app.dataRoot = uigetdir(pwd, 'Data root directory');
            app.EditField_dataRoot.Value = app.dataRoot;
            %%
            d = dir(app.dataRoot);
            f = {d([d.isdir]).name};
            subList = f(3:end);
            app.DropDown_subMark.Items = subList;
            app.DropDown_subMark.Value = subList{1};
            %%
            try
                app.InputPath = fullfile(app.dataRoot,app.subMark);
                app.OutputPath = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
                app.m2mPath = fullfile(app.dataRoot,app.subMark, ['m2m_' app.subMark]);
                disp(['InputPath is ' app.InputPath]);
                disp(['OutputPath is ' app.OutputPath]);
            catch
            end
        end

        % Value changed function: DropDown_subMark
        function DropDown_subMarkValueChanged(app, event)
            %% default 
            app.subMark = app.DropDown_subMark.Value;
            app.InputPath = fullfile(app.dataRoot,app.subMark);
            app.cfg.ROI.num = 0;
            app.cfg.Penalty.num = 0;
            app.cfg.ROI.table = table('Size',[0,3],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.cfg.Penalty.table = table('Size',[0,3],'VariableTypes',app.varTypes,'VariableNames',app.varNames);
            app.graymatterCheckBox.Value = false;
            app.whitematterCheckBox.Value = false;
            %% figure
            LFPath = fullfile(app.dataRoot,app.subMark,'leadfield');
            LFFile = fullfile(LFPath,[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
            %% questdlg
            if exist(LFFile,'file')~=2
                answer = questdlg('Leadfield file for gray matter surface not existed! Would you like to run simNIBS to get middle gray matter surface?', ...
                    ['subMark: ' app.subMark],...
                    'Yes', 'No','Yes');
                switch answer
                    case 'Yes'
                        SIMNIBS_LF_tri(app.dataRoot,app.subMark);
                    case 'No'
                        cla(app.UIAxes_figure);
                        title(app.UIAxes_figure,[app.subMark ': No gray matter surface for display.'],'FontWeight','normal');
                        app.gmsTR = [];
                end
            end
            if exist(LFFile,'file')==2
                m0 = mesh_load_hdf5(LFFile);
                m1 = m0(2).mesh;
                gmS = mesh_extract_regions(m1, 'region_idx', [1 2]); % gray matter surface in both hemisphere
                app.gmsTR = triangulation(double(gmS.triangles),gmS.nodes);
                app.hobj = trimesh(app.gmsTR.ConnectivityList,app.gmsTR.Points(:,1),app.gmsTR.Points(:,2),app.gmsTR.Points(:,3),'EdgeColor','none',...
                    'FaceColor', [130/255 130/255 130/255],'FaceAlpha',0.05,'Parent',app.UIAxes_figure);
                title(app.UIAxes_figure,app.subMark,'FontWeight','normal');
                lighting(app.UIAxes_figure,'gouraud');
                rotate3d(app.UIAxes_figure,'on');
                set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
                axis(app.UIAxes_figure,'off');
                material(app.hobj,'dull');
            end
            try
                app.OutputPath = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
                app.m2mPath = fullfile(app.dataRoot,app.subMark, ['m2m_' app.subMark]);
            catch ME

            end
        end

        % Value changed function: EditField_simMark
        function EditField_simMarkValueChanged(app, event)
            app.simMark = app.EditField_simMark.Value;
            app.OutputPath = fullfile(app.dataRoot,app.subMark,'TI_sim_result',app.simMark);
            disp(['OutputPath is ' app.OutputPath]);
            if(isfolder(app.OutputPath))
                msgbox('This simMark has been used, the old data would be overwritten!')
            end

        end

        % Selection changed function: ElementtypeButtonGroup
        function ElementtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ElementtypeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'triangle'
                    app.cfg.type = 'tri';
                    LFPath = fullfile(app.dataRoot,app.subMark,'leadfield');
                    LFFile = fullfile(LFPath,[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
                    if exist(LFFile,'file')~=2
                        answer = questdlg('Leadfield file for gray matter surface not existed! Would you like to run simNIBS to get middle gray matter surface?', ...
    	                    ['subMark: ' app.subMark],...
                            'Yes', 'No','Yes');
                        switch answer
                            case 'Yes'
                                SIMNIBS_LF_tri(app.dataRoot,app.subMark);
                            case 'No'
                        end
                    end
                case 'tetraheron'
                    app.cfg.type = 'tet';
                    LFPath = fullfile(app.dataRoot,app.subMark,'leadfield_tet');
                    LFFile = fullfile(LFPath,[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
                    if exist(LFFile,'file')~=2
                        answer = questdlg('Leadfield file for tetrahedron brain mesh not existed! Would you like to run simNIBS to get middle gray matter surface?', ...
    	                    ['subMark: ' app.subMark],...
                            'Yes', 'No','Yes');
                        switch answer
                            case 'Yes'
                                SIMNIBS_LF_tet(app.dataRoot,app.subMark);
                            case 'No'
                        end
                    end

            end
            disp(['Element type : ' selectedButton.Text]);
        end

        % Button pushed function: Button_ROIAdd
        function Button_ROIAddPushed(app, event)
            appTarget = Target_eng(app,'ROI');
            uiwait(appTarget.UI_DialogROI);
        end

        % Button pushed function: Button_PenaltyAdd
        function Button_PenaltyAddPushed(app, event)
            appTarget = Target_eng(app,'Penalty');
            uiwait(appTarget.UI_DialogROI);
        end

        % Cell selection callback: UITable_ROI
        function UITable_ROICellSelection(app, event)
            if ~isempty(event.Indices)
                app.row_ROI_table_selected = event.Indices(1);
            end
        end

        % Cell selection callback: UITable_Penalty
        function UITable_PenaltyCellSelection(app, event)
            if ~isempty(event.Indices)
                app.row_Penalty_table_selected = event.Indices(1);
            end
        end

        % Button pushed function: Button_ROIMinus
        function Button_ROIMinusPushed(app, event)
            if app.row_ROI_table_selected>0
                app.UITable_ROI.Data(app.row_ROI_table_selected,:) = [];
                app.cfg.ROI.num = app.cfg.ROI.num - 1;
                app.cfg.ROI.table(app.row_ROI_table_selected,:) = [];
                disp('ROI table:');
                disp(app.cfg.ROI.table);
            end
        end

        % Button pushed function: Button_PenaltyMinus
        function Button_PenaltyMinusPushed(app, event)
            if app.row_Penalty_table_selected>0
                app.UITable_Penalty.Data(app.row_Penalty_table_selected,:) = [];
                app.cfg.Penalty.num = app.cfg.Penalty.num - 1;
                app.cfg.Penalty.table(app.row_Penalty_table_selected,:) = [];
                disp('Penalty table:');
                disp(app.cfg.Penalty.table);
            end
        end

        % Button pushed function: Button_ROIDisplay
        function Button_ROIDisplayPushed(app, event)
            [app.ROIview.az,app.ROIview.el]=view(app.UIAxes_figure);
            hold(app.UIAxes_figure,'off');
            cla(app.UIAxes_figure);
            if isempty(app.gmsTR)
                LFPath = fullfile(app.dataRoot,app.subMark,'leadfield');
                LFFile = fullfile(LFPath,[app.subMark '_leadfield_EEG10-10_UI_Jurak_2007.hdf5']);
                if exist(LFFile,'file')~=2
                    disp('Leadfield file for gray matter surface not existed!');
                end
                m0 = mesh_load_hdf5(LFFile);
                m1 = m0(2).mesh;
                gmS = mesh_extract_regions(m1, 'region_idx', [1 2]); % gray matter surface in both hemisphere
                app.gmsTR = triangulation(double(gmS.triangles),gmS.nodes);
            end
            %% plot
            trimesh(app.gmsTR.ConnectivityList,app.gmsTR.Points(:,1),app.gmsTR.Points(:,2),app.gmsTR.Points(:,3),'EdgeColor','none',...
                'FaceColor', [130/255 130/255 130/255],'FaceAlpha',0.05,'Parent',app.UIAxes_figure);
            view(app.UIAxes_figure,app.ROIview.az,app.ROIview.el);
            hold(app.UIAxes_figure,'on');
            rotate3d(app.UIAxes_figure,'on');
            set(app.UIAxes_figure,'DataAspectRatio',[1,1,1]);
            axis(app.UIAxes_figure,'off');
            if app.cfg.ROI.num>0
                ROI_node_idx = false(size(app.gmsTR.Points,1),app.cfg.ROI.num);
                coord_sub = mni2subject_coords(app.cfg.ROI.table.CoordMNI, app.m2mPath);
                for i = 1 : app.cfg.ROI.num
                    ROI_node_idx(:,i) = vecnorm(app.gmsTR.Points-coord_sub(i,:),2,2)<app.cfg.ROI.table.Radius(i);
                end
                ROI_node_idx = any(ROI_node_idx,2);
                ROI_tri_idx = sum(ROI_node_idx(app.gmsTR.ConnectivityList),2)>1;
                app.ROI_TR = simpleTR(triangulation(app.gmsTR.ConnectivityList(ROI_tri_idx,:),app.gmsTR.Points));
                trimesh(app.ROI_TR.ConnectivityList,app.ROI_TR.Points(:,1),app.ROI_TR.Points(:,2),app.ROI_TR.Points(:,3),'EdgeColor','none',...
                    'FaceColor', app.ROIColor/255,'FaceAlpha',1,'Parent',app.UIAxes_figure);
            end
        end

        % Button pushed function: Button_PenaltyDisplay
        function Button_PenaltyDisplayPushed(app, event)
            hold(app.UIAxes_figure,'on');
            if app.cfg.Penalty.num>0
                Penalty_node_idx = false(size(app.gmsTR.Points,1),app.cfg.Penalty.num);
                coord_sub = mni2subject_coords(app.cfg.Penalty.table.CoordMNI, app.m2mPath);
                for i = 1 : app.cfg.Penalty.num
                    Penalty_node_idx(:,i) = vecnorm(app.gmsTR.Points-coord_sub(i,:),2,2)<app.cfg.Penalty.table.Radius(i);
                end
                Penalty_node_idx = any(Penalty_node_idx,2);
                Penalty_tri_idx = sum(Penalty_node_idx(app.gmsTR.ConnectivityList),2)>1;
                app.Penalty_TR = simpleTR(triangulation(app.gmsTR.ConnectivityList(Penalty_tri_idx,:),app.gmsTR.Points));
                trimesh(app.Penalty_TR.ConnectivityList,app.Penalty_TR.Points(:,1),app.Penalty_TR.Points(:,2),app.Penalty_TR.Points(:,3),'EdgeColor','none',...
                    'FaceColor', app.PenaltyColor/255,'FaceAlpha',1,'Parent',app.UIAxes_figure);
            end
        end

        % Callback function
        function CheckBox_triValueChanged(app, event)
            value = app.CheckBox_tri.Value;
            if value
                app.CheckBox_tet.Value = false;
                app.eletype = 'tri';
            end
        end

        % Callback function
        function CheckBox_tetValueChanged(app, event)
            value = app.CheckBox_tet.Value;
            if value
                app.CheckBox_tri.Value = false;
                app.eletype = 'tet';
            end
        end

        % Value changed function: graymatterCheckBox
        function graymatterCheckBoxValueChanged(app, event)
            app.cfg.ntGM = app.graymatterCheckBox.Value;
            if app.cfg.ntGM
                ntGMFile = fullfile(app.dataRoot,app.subMark,'orientation','nt_elem_GM.mat');
                if exist(ntGMFile,'file')~=2
                       answer = questdlg('Orientation file for gray matter not existed! Would you like to get it?', ...
    	                    ['subMark: ' app.subMark],...
                            'Yes', 'No','Yes');
                        switch answer
                            case 'Yes'
                                GMNT(app.dataRoot,app.subMark);
                            case 'No'
                                app.graymatterCheckBox.Value = false;
                                app.cfg.ntGM = false;
                                disp('Orientation file for gray matter not building...');
                        end
                    
                else
                    disp('Orientation file for gray matter existed...');
                end
            end
        end

        % Value changed function: whitematterCheckBox
        function whitematterCheckBoxValueChanged(app, event)
            app.cfg.ntWM = app.whitematterCheckBox.Value;
            if app.cfg.ntWM
                ntWMFile = fullfile(app.dataRoot,app.subMark,'orientation','nt_elem_WM.mat');
                if exist(ntWMFile,'file')~=2
                       answer = questdlg('Orientation file for white matter not existed! Would you like to get it?', ...
    	                    ['subMark: ' app.subMark],...
                            'Read from FSL', 'ACID','No','ACID');
                        switch answer
                            case 'Read from FSL'
                                WMNT(app.dataRoot,app.subMark,1);
                            case 'ACID'
                                WMNT(app.dataRoot,app.subMark,2);
                            case 'No'
                                app.whitematterCheckBox.Value = false;
                                app.cfg.ntWM = false;
                                disp('Orientation file for white matter not building...');
                        end
                    
                else
                    disp('Orientation file for white matter existed...');
                end
            end
        end

        % Value changed function: electrodenumDropDown
        function electrodenumDropDownValueChanged(app, event)
            app.cfg.elecNum = app.electrodenumDropDown.Value;  
        end

        % Value changed function: electrodecandidateEditField
        function electrodecandidateEditFieldValueChanged(app, event)
            app.cfg.elecCandNum = app.electrodecandidateEditField.Value;
        end

        % Button pushed function: CalculationButton
        function CalculationButtonPushed(app, event)
            %%check
            close
        end

        % Value changed function: PenaltypEditField
        function PenaltypEditFieldValueChanged(app, event)
            app.cfg.Penalty.coef = app.PenaltypEditField.Value;      
        end

        % Value changed function: OtheralphaEditField
        function OtheralphaEditFieldValueChanged(app, event)
            app.cfg.method_Other = app.OtheralphaEditField.Value;           
        end

        % Value changed function: ROIalphaEditField
        function ROIalphaEditFieldValueChanged(app, event)
            app.cfg.method_ROI = app.ROIalphaEditField.Value;          
        end

        % Value changed function: ROIthresholdEditField
        function ROIthresholdEditFieldValueChanged(app, event)
            app.cfg.thres = app.ROIthresholdEditField.Value;          
        end

        % Close request function: UI_OpenTIPS
        function UI_OpenTIPSCloseRequest(app, event)
            assignin('base','TIcfg',app.cfg);
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UI_OpenTIPS.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {663, 663};
                app.GridLayout.ColumnWidth = {'1x'};
                app.Panel_Plots.Layout.Row = 2;
                app.Panel_Plots.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {450, '1x'};
                app.Panel_Plots.Layout.Row = 1;
                app.Panel_Plots.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI_OpenTIPS = uifigure('Visible', 'off');
            app.UI_OpenTIPS.Name = 'OpenTIPS';
            app.UI_OpenTIPS.CloseRequestFcn = createCallbackFcn(app, @UI_OpenTIPSCloseRequest, true);
            app.UI_OpenTIPS.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UI_OpenTIPS);
            app.Panel_Settings = uipanel(app.GridLayout);
            app.Grid_Settings = uigridlayout(app.Panel_Settings);
            app.ElementtypeButtonGroup = uibuttongroup(app.Grid_Settings);
            app.ElementtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElementtypeButtonGroupSelectionChanged, true);
            app.ElementtypeButtonGroup.Title = 'Element type';
            app.tetraheronButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.tetraheronButton.Text = 'tetraheron';
            app.tetraheronButton.Value = true;
            app.triangleButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.triangleButton.Text = 'triangle';
            app.Panel_simMark = uipanel(app.Grid_Settings);
            app.Panel_simMark.Title = 'simMark';
            app.EditField_simMark = uieditfield(app.Panel_simMark, 'text');
            app.EditField_simMark.ValueChangedFcn = createCallbackFcn(app, @EditField_simMarkValueChanged, true);
            app.Panel_subMark = uipanel(app.Grid_Settings);
            app.Panel_subMark.Title = 'Subject';
            app.DropDown_subMark = uidropdown(app.Panel_subMark);
            app.DropDown_subMark.ValueChangedFcn = createCallbackFcn(app, @DropDown_subMarkValueChanged, true);
            app.Panel_dataRoot = uipanel(app.Grid_Settings);
            app.Panel_dataRoot.Title = 'Data root';
            app.Grid_dataRoot = uigridlayout(app.Panel_dataRoot);
            app.EditField_dataRoot = uieditfield(app.Grid_dataRoot, 'text');
            app.Button_dataRoot = uibutton(app.Grid_dataRoot, 'push');
            app.Button_dataRoot.ButtonPushedFcn = createCallbackFcn(app, @Button_dataRootPushed, true);
            app.Button_dataRoot.Text = '...';
            app.orientationPanel = uipanel(app.Grid_Settings);
            app.orientationPanel.Title = 'orientation';
            app.whitematterCheckBox = uicheckbox(app.orientationPanel);
            app.whitematterCheckBox.ValueChangedFcn = createCallbackFcn(app, @whitematterCheckBoxValueChanged, true);
            app.whitematterCheckBox.Text = 'white matter';
            app.graymatterCheckBox = uicheckbox(app.orientationPanel);
            app.graymatterCheckBox.ValueChangedFcn = createCallbackFcn(app, @graymatterCheckBoxValueChanged, true);
            app.graymatterCheckBox.Text = 'gray matter';
            app.Panel = uipanel(app.Grid_Settings);
            app.OptimalcoefficientsPanel = uipanel(app.Grid_Settings);
            app.OptimalcoefficientsPanel.Title = 'Optimal coefficients';
            app.coefficient = uigridlayout(app.OptimalcoefficientsPanel);
            app.ROIthresholdEditFieldLabel = uilabel(app.coefficient);
            app.ROIthresholdEditFieldLabel.Text = 'ROI threshold';
            app.ROIthresholdEditField = uieditfield(app.coefficient, 'numeric');
            app.ROIthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ROIthresholdEditFieldValueChanged, true);
            app.ROIthresholdEditField.Value = 0.2;
            app.ROIalphaEditFieldLabel = uilabel(app.coefficient);
            app.ROIalphaEditFieldLabel.Text = 'ROI alpha';
            app.ROIalphaEditField = uieditfield(app.coefficient, 'numeric');
            app.ROIalphaEditField.ValueChangedFcn = createCallbackFcn(app, @ROIalphaEditFieldValueChanged, true);
            app.OtheralphaEditFieldLabel = uilabel(app.coefficient);
            app.OtheralphaEditFieldLabel.Text = 'Other alpha';
            app.OtheralphaEditField = uieditfield(app.coefficient, 'numeric');
            app.OtheralphaEditField.ValueChangedFcn = createCallbackFcn(app, @OtheralphaEditFieldValueChanged, true);
            app.OtheralphaEditField.Value = 2;
            app.PenaltypEditFieldLabel = uilabel(app.coefficient);
            app.PenaltypEditFieldLabel.Text = 'Penalty p';
            app.PenaltypEditField = uieditfield(app.coefficient, 'numeric');
            app.PenaltypEditField.ValueChangedFcn = createCallbackFcn(app, @PenaltypEditFieldValueChanged, true);
            app.PenaltypEditField.Value = 2;
            app.CalculationButton = uibutton(app.coefficient, 'push');
            app.CalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.CalculationButton.Text = 'Calculation';
            app.electrodecandidateEditFieldLabel = uilabel(app.coefficient);
            app.electrodecandidateEditFieldLabel.Text = 'electrode candidate';
            app.electrodecandidateEditField = uieditfield(app.coefficient, 'numeric');
            app.electrodecandidateEditField.ValueChangedFcn = createCallbackFcn(app, @electrodecandidateEditFieldValueChanged, true);
            app.electrodecandidateEditField.Value = 20;
            app.electrodenumDropDownLabel = uilabel(app.coefficient);
            app.electrodenumDropDownLabel.Text = {'electrode num'; ''};
            app.electrodenumDropDown = uidropdown(app.coefficient);
            app.electrodenumDropDown.Items = {'4', '6', '8'};
            app.electrodenumDropDown.ValueChangedFcn = createCallbackFcn(app, @electrodenumDropDownValueChanged, true);
            app.electrodenumDropDown.Value = '4';
            app.PenaltyPanel = uipanel(app.Grid_Settings);
            app.PenaltyPanel.Title = 'Penalty';
            app.UITable_Penalty = uitable(app.PenaltyPanel);
            app.UITable_Penalty.ColumnName = {'Name'; 'MNI_XYZ'; 'Radius'};
            app.UITable_Penalty.RowName = {'1'};
            app.UITable_Penalty.ColumnEditable = true;
            app.UITable_Penalty.CellSelectionCallback = createCallbackFcn(app, @UITable_PenaltyCellSelection, true);
            app.Button_PenaltyAdd = uibutton(app.PenaltyPanel, 'push');
            app.Button_PenaltyAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyAddPushed, true);
            app.Button_PenaltyAdd.Text = '+';
            app.Button_PenaltyMinus = uibutton(app.PenaltyPanel, 'push');
            app.Button_PenaltyMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyMinusPushed, true);
            app.Button_PenaltyMinus.Text = '-';
            app.Button_PenaltyDisplay = uibutton(app.PenaltyPanel, 'push');
            app.Button_PenaltyDisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_PenaltyDisplayPushed, true);
            app.Button_PenaltyDisplay.Text = 'D';
            app.ROIPanel = uipanel(app.Grid_Settings);
            app.ROIPanel.Title = 'ROI';
            app.UITable_ROI = uitable(app.ROIPanel);
            app.UITable_ROI.ColumnName = {'Name'; 'MNI_XYZ'; 'Radius'};
            app.UITable_ROI.RowName = {'1'};
            app.UITable_ROI.ColumnEditable = true;
            app.UITable_ROI.CellSelectionCallback = createCallbackFcn(app, @UITable_ROICellSelection, true);
            app.Button_ROIAdd = uibutton(app.ROIPanel, 'push');
            app.Button_ROIAdd.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIAddPushed, true);
            app.Button_ROIAdd.Text = '+';
            app.Button_ROIMinus = uibutton(app.ROIPanel, 'push');
            app.Button_ROIMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIMinusPushed, true);
            app.Button_ROIMinus.Text = '-';
            app.Button_ROIDisplay = uibutton(app.ROIPanel, 'push');
            app.Button_ROIDisplay.ButtonPushedFcn = createCallbackFcn(app, @Button_ROIDisplayPushed, true);
            app.Button_ROIDisplay.Text = 'D';
            app.Panel_Plots = uipanel(app.GridLayout);
            app.Grid_Plots = uigridlayout(app.Panel_Plots);
            app.UIAxes_figure = uiaxes(app.Grid_Plots);
            title(app.UIAxes_figure, 'Title')
            xlabel(app.UIAxes_figure, 'X')
            ylabel(app.UIAxes_figure, 'Y')
            app.Image_Logo = uiimage(app.Grid_Plots);
            app.Image_Logo.ImageSource = 'opentips.png';
            app.Panel_dataRoot_2 = uipanel(app.Grid_Plots);
            app.Panel_dataRoot_2.Title = 'Import configuration';
            app.Grid_dataRoot_2 = uigridlayout(app.Panel_dataRoot_2);
            app.EditField_dataRoot_2 = uieditfield(app.Grid_dataRoot_2, 'text');
            app.Button_dataRoot_2 = uibutton(app.Grid_dataRoot_2, 'push');
            app.Button_dataRoot_2.Text = '...';
            % Show the figure after all components are created
            app.UI_OpenTIPS.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenTIPS_eng

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI_OpenTIPS)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI_OpenTIPS)
        end
    end
end